classdef plot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SpringTabGroup                matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        GoButton                      matlab.ui.control.Button
        LoadingMotorTabGroup          matlab.ui.container.TabGroup
        Tab1                          matlab.ui.container.Tab
        UnlatchingMotorTabGroup       matlab.ui.container.TabGroup
        Tab2                          matlab.ui.container.Tab
        LatchTabGroup                 matlab.ui.container.TabGroup
        Tab3                          matlab.ui.container.Tab
        MassTabGroup                  matlab.ui.container.TabGroup
        Tab4                          matlab.ui.container.Tab
        ParametersLabel               matlab.ui.control.Label
        UnlatchingMotorLabel          matlab.ui.control.Label
        LoadingMotorLabel             matlab.ui.control.Label
        GraphingCorner                matlab.ui.container.TabGroup
        GraphingCorner_kinematics     matlab.ui.container.Tab
        KinematicsOutputOptionsLabel  matlab.ui.control.Label
        forcedisp                     matlab.ui.control.CheckBox
        latchkinematicsCheckBox       matlab.ui.control.CheckBox
        loadkinematicsCheckBox        matlab.ui.control.CheckBox
        LoadingphaseLabel             matlab.ui.control.Label
        UnlatchingandlaunchingphaseLabel  matlab.ui.control.Label
        one_D                         matlab.ui.container.Tab
        OD_a_maxCheckBox              matlab.ui.control.CheckBox
        xaxisLabel                    matlab.ui.control.Label
        OD_y_maxCheckBox              matlab.ui.control.CheckBox
        OD_y_unlatchCheckBox          matlab.ui.control.CheckBox
        OD_t_LCheckBox                matlab.ui.control.CheckBox
        OD_v_toCheckBox               matlab.ui.control.CheckBox
        OD_P_maxCheckBox              matlab.ui.control.CheckBox
        OD_t_toCheckBox               matlab.ui.control.CheckBox
        OD_KE_maxCheckBox             matlab.ui.control.CheckBox
        OD_IV1DropDown                matlab.ui.control.DropDown
        yaxisOutputOptionsLabel       matlab.ui.control.Label
        pixelsofresolutionLabel       matlab.ui.control.Label
        OD_n                          matlab.ui.control.NumericEditField
        xmaxEditFieldLabel            matlab.ui.control.Label
        OD_x_log_space                matlab.ui.control.Switch
        ComparetoDirectActuationCheckBox  matlab.ui.control.CheckBox
        xminEditFieldLabel            matlab.ui.control.Label
        OD_xmin                       matlab.ui.control.NumericEditField
        OD_xmax                       matlab.ui.control.NumericEditField
        ODCP_TabGroup                 matlab.ui.container.TabGroup
        CouplingParameterTabs         matlab.ui.container.Tab
        NumberofCouplingParametersLabel  matlab.ui.control.Label
        ODCP_num                      matlab.ui.control.NumericEditField
        GraphingCorner_heatmap        matlab.ui.container.Tab
        a_maxCheckBox                 matlab.ui.control.CheckBox
        xaxisLabel_2                  matlab.ui.control.Label
        yaxisLabel                    matlab.ui.control.Label
        y_maxCheckBox                 matlab.ui.control.CheckBox
        y_unlatchCheckBox             matlab.ui.control.CheckBox
        t_LCheckBox                   matlab.ui.control.CheckBox
        v_toCheckBox                  matlab.ui.control.CheckBox
        P_maxCheckBox                 matlab.ui.control.CheckBox
        t_toCheckBox                  matlab.ui.control.CheckBox
        KE_maxCheckBox                matlab.ui.control.CheckBox
        IV1DropDown                   matlab.ui.control.DropDown
        xminEditFieldLabel_2          matlab.ui.control.Label
        xmin                          matlab.ui.control.NumericEditField
        xmaxEditFieldLabel_2          matlab.ui.control.Label
        xmax                          matlab.ui.control.NumericEditField
        yminLabel                     matlab.ui.control.Label
        ymin                          matlab.ui.control.NumericEditField
        ymaxLabel                     matlab.ui.control.Label
        ymax                          matlab.ui.control.NumericEditField
        HeatmapOutputOptionsLabel     matlab.ui.control.Label
        pixelsofresolutionLabel_2     matlab.ui.control.Label
        n                             matlab.ui.control.NumericEditField
        x_log_space                   matlab.ui.control.Switch
        y_log_space                   matlab.ui.control.Switch
        MakeLaMSAZonePlotCheckBox     matlab.ui.control.CheckBox
        CP_DropDown_Label_3           matlab.ui.control.Label
        CP_DropDown_3                 matlab.ui.control.DropDown
        CP_Relationship_Label_3       matlab.ui.control.Label
        zEditFieldLabel_3             matlab.ui.control.Label
        CP_Relationship_3             matlab.ui.control.EditField
        HMCPX_TabGroup                matlab.ui.container.TabGroup
        CouplingParameterTabs_2       matlab.ui.container.Tab
        HMCPY_TabGroup                matlab.ui.container.TabGroup
        CouplingParameterTabs_3       matlab.ui.container.Tab
        NumberofCouplingParametersLabel_2  matlab.ui.control.Label
        HMCPX_num                     matlab.ui.control.NumericEditField
        NumberofCouplingParametersLabel_3  matlab.ui.control.Label
        HMCPY_num                     matlab.ui.control.NumericEditField
        IV2DropDown                   matlab.ui.control.DropDown
        GraphingCorner_sensitivity    matlab.ui.container.Tab
        sensitivity_vars              matlab.ui.control.ListBox
        MetricButtonGroup             matlab.ui.container.ButtonGroup
        ymaxButton                    matlab.ui.control.RadioButton
        yunlatchButton                matlab.ui.control.RadioButton
        tLButton                      matlab.ui.control.RadioButton
        vtoButton                     matlab.ui.control.RadioButton
        PmaxButton                    matlab.ui.control.RadioButton
        ttoButton                     matlab.ui.control.RadioButton
        KEmaxButton                   matlab.ui.control.RadioButton
        SensitivityLabel              matlab.ui.control.Label
        GraphingCornerLabel           matlab.ui.control.Label
        GraphButton                   matlab.ui.control.Button
        savesolutionCheckBox          matlab.ui.control.CheckBox
        saveParametersButton          matlab.ui.control.Button
        saveParametersEditField       matlab.ui.control.EditField
        loadParametersButton          matlab.ui.control.Button
        loadParametersDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        SpringTabs        = matlab.ui.container.Tab
        SpringEditFields  = matlab.ui.control.NumericEditField
        SpringLabels      = matlab.ui.control.Label
        
        LoadingMotorTabs        = matlab.ui.container.Tab
        LoadingMotorEditFields  = matlab.ui.control.NumericEditField
        LoadingMotorLabels      = matlab.ui.control.Label
        
        UnlatchingMotorTabs        = matlab.ui.container.Tab
        UnlatchingMotorEditFields  = matlab.ui.control.NumericEditField
        UnlatchingMotorLabels      = matlab.ui.control.Label
        
        MassTabs        = matlab.ui.container.Tab
        MassEditFields  = matlab.ui.control.NumericEditField
        MassLabels      = matlab.ui.control.Label
        
        LatchTabs        = matlab.ui.container.Tab
        LatchEditFields  = matlab.ui.control.NumericEditField
        LatchLabels      = matlab.ui.control.Label
        
        SpringParamMap = containers.Map;
        SpringParamIndices = containers.Map;
        
        LoadingMotorParamMap = containers.Map;
        LoadingMotorParamIndices = containers.Map;
        
        UnlatchingMotorParamMap = containers.Map;
        UnlatchingMotorParamIndices = containers.Map;
        
        MassParamMap = containers.Map;
        MassParamIndices = containers.Map;
        
        LatchParamMap = containers.Map;
        LatchParamIndices = containers.Map;
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % New additions for coupling parameters %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % 1-D Coupling Tab
        ODCPTabs = matlab.ui.container.Tab;
        ODCPDropDowns = matlab.ui.control.DropDown;
        ODCPRelationships = matlab.ui.control.EditField;
        
        % 2-D Coupling Tabs
        HMCPXTabs = matlab.ui.container.Tab;
        HMCPXDropDowns = matlab.ui.control.DropDown;
        HMCPXRelationships = matlab.ui.control.EditField;
        
        HMCPYTabs = matlab.ui.container.Tab;
        HMCPYDropDowns = matlab.ui.control.DropDown;
        HMCPYRelationships = matlab.ui.control.EditField;
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %  For saving parameters and workspace  %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        ParametersTabGroups = matlab.ui.container.Tab;
        CouplingNumberGroups = matlab.ui.control.EditField;
        DropDownSelectionGroups = matlab.ui.control.DropDown;
        
    end
    
    properties (Access = private)
        
        DDvars = [];
        
        load_vars;
        latch_vars;
        spring_vars;
        lm_vars;
        um_vars;
        dd_vars;
        param_templates;
        
        %dd_vars_labels;
        %axis_labels_dict;
        %dropdown_items_dict;
        %dropdown_items_opposite_dict;
    end
    
    methods (Access = private)
        
        function update_dd_vars(app)
            app.dd_vars = [app.load_vars app.latch_vars app.spring_vars app.lm_vars app.um_vars];
%             for i=1:length(app.dd_vars)
%                 app.dd_vars_labels{i} = app.dropdown_items_dict(app.dd_vars{i});
%             end
            app.IV1DropDown.Items = app.dd_vars;
            app.IV2DropDown.Items = app.dd_vars;
            app.OD_IV1DropDown.Items = app.dd_vars;
            app.sensitivity_vars.Items = app.dd_vars;            
        end
        
        % retrieve value from editfield given dropdown selection and
        % editfield index
        function val = getEditFieldVal(app, name, index)
            if startsWith(name, "load ")
                val = app.MassEditFields(index).Value;
            elseif startsWith(name, "latch ")
                val = app.LatchEditFields(index).Value;
            elseif startsWith(name, "spring ")
                val = app.SpringEditFields(index).Value;
            elseif startsWith(name, "loading motor ")
                val = app.LoadingMotorEditFields(index).Value;
            elseif startsWith(name, "unlatching motor ")
                val = app.UnlatchingMotorEditFields(index).Value;
            end
        end
        
        function limits = getEditFieldLimits(app, name, index)
            if startsWith(name, "load ")
                limits = app.MassEditFields(index).Limits;
            elseif startsWith(name, "latch ")
                limits = app.LatchEditFields(index).Limits;
            elseif startsWith(name, "spring ")
                limits = app.SpringEditFields(index).Limits;
            elseif startsWith(name, "loading motor ")
                limits = app.LoadingMotorEditFields(index).Limits;
            elseif startsWith(name, "unlatching motor ")
                limits = app.UnlatchingMotorEditFields(index).Limits;
            end
        end
        
        % modify the value of an editfield given dropdown selection,
        % editfield index, and a new value
        function changeEditField(app, name, index, newVal)
            if startsWith(name, "load ")
                app.MassEditFields(index).Value = newVal;
            elseif startsWith(name, "latch")
                app.LatchEditFields(index).Value = newVal;
            elseif startsWith(name, "spring")
                app.SpringEditFields(index).Value = newVal;
            elseif startsWith(name, "loading motor")
                app.LoadingMotorEditFields(index).Value = newVal;
            elseif startsWith(name, "unlatching motor")
                app.UnlatchingMotorEditFields(index).Value = newVal;
            end
        end
        
        % gnarly indexing to figure out the editfield index from
        % the dropdown index
        function efIndex = ddIndexToefIndex(app, ddIndex, param)
            efIndex = ddIndex;
            
            is_loadingMotor = false;
            if startsWith(param, "load ")
                object = app.MassTabGroup.SelectedTab.Title;
            elseif startsWith(param, "latch")
                object = app.LatchTabGroup.SelectedTab.Title;
            elseif startsWith(param, "spring")
                object = app.SpringTabGroup.SelectedTab.Title;
            elseif startsWith(param, "loading motor")
                object = app.LoadingMotorTabGroup.SelectedTab.Title;
                is_loadingMotor = true;
            elseif startsWith(param, "unlatching motor")
                object = app.UnlatchingMotorTabGroup.SelectedTab.Title;
            end
            
            loadObjects = app.MassTabGroup.Children;
            for ii = 1:length(loadObjects)
                name = loadObjects(ii).Title;
                if strcmp(name, object)
                    return
                end
                if ~strcmp(name, app.MassTabGroup.SelectedTab.Title)
                    index = app.MassParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
            latchObjects = app.LatchTabGroup.Children;
            for ii = 1:length(latchObjects)
                name = latchObjects(ii).Title;
                if strcmp(name, object)
                    return
                end
                if ~strcmp(name, app.LatchTabGroup.SelectedTab.Title)
                    index = app.LatchParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
            springObjects = app.SpringTabGroup.Children;
            for ii = 1:length(springObjects)
                name = springObjects(ii).Title;
                if strcmp(name, object)
                    return
                end
                if ~strcmp(name, app.SpringTabGroup.SelectedTab.Title)
                    index = app.SpringParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
            lmObjects = app.LoadingMotorTabGroup.Children;
            for ii = 1:length(lmObjects)
                name = lmObjects(ii).Title;
                if strcmp(name, object) && is_loadingMotor
                    return
                end
                if ~strcmp(name, app.LoadingMotorTabGroup.SelectedTab.Title)
                    index = app.LoadingMotorParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
            umObjects = app.UnlatchingMotorTabGroup.Children;
            for ii = 1:length(umObjects)
                name = umObjects(ii).Title;
                if strcmp(name, object)
                    return
                end
                if ~strcmp(name, app.UnlatchingMotorTabGroup.SelectedTab.Title)
                    index = app.UnlatchingMotorParamIndices(name);
                    efIndex = efIndex + (index(2)-index(1)+1);
                end
            end
            
        end
        
        % Takes num from user input and then initiates the specified CP Tab
        % Group to num number of tabs, each containing a coupling parameter
        % dropdown and a coupling relationship edit field.
        function initiateCPTabs(app, num, tabGroupString, y_center, varString)
            % Uses feval to evaluate parameter names
            tabGroup = eval(['app.' tabGroupString '_TabGroup']);
            
            % Deletes the default tab
            delete(get(tabGroup, 'Children'));
            
            % Creates tabs for each element until num
            for index = 1:num
                % Displays new tabs
                name = "CP " + index;
                tab = uitab(tabGroup, 'Title', name);
                
                % Displays the coupling parameter label
                % dropdownLabel = eval(['app.' tabGroupString 'DropDownLabels']);
                dropdownLabel(index) = uilabel(tab);
                dropdownLabel(index).HorizontalAlignment = 'center';
                dropdownLabel(index).FontWeight = 'bold';
                dropdownLabel(index).Text = 'Coupling Parameter';
                dropdownLabel(index).Position = [5 y_center+20 140 22];
                
                % Displays the coupling parameter dropdown
                % dropdown = eval(['app.' tabGroupString 'DropDowns']);
                dropdown(index) = uidropdown(tab);
                dropdown(index).Items = app.dd_vars;
                dropdown(index).Position = [150 y_center+20 120 22];

                % Displays the coupling relationship label
                % relationshipLabel = eval(['app.' tabGroupString 'RelationshipLabels']);
                relationshipLabel(index) = uilabel(tab);
                relationshipLabel(index).HorizontalAlignment = 'center';
                relationshipLabel(index).FontWeight = 'bold';
                relationshipLabel(index).Text = 'Coupling Relationship';
                relationshipLabel(index).Position = [5 y_center-10 140 22];
                
                % Displays the coupling relationship edit-field
                % relationship = eval(['app.' tabGroupString 'Relationships']);
                relationship(index) = uieditfield(tab, 'text');
                relationship(index).Position = [180 y_center-10 90 22];
                relationship(index).Value = varString;
                
                % Displays the Z Label for the edit-field
                % zlab = eval(['app.' tabGroupString 'ZLabels']);
                zlab(index) = uilabel(tab);
                zlab(index).HorizontalAlignment = 'center';
                zlab(index).Text = 'z =';
                zlab(index).Position = [140 y_center-10 40 22];
                                    
            end
            
            % Stores the important items back the temporary arrays
            if tabGroupString == "ODCP"
                app.ODCPDropDowns = dropdown;
                app.ODCPRelationships = relationship;
            elseif tabGroupString == "HMCPX"
                app.HMCPXDropDowns = dropdown;
                app.HMCPXRelationships = relationship;
            else
                app.HMCPYDropDowns = dropdown;
                app.HMCPYRelationships = relationship;
            end     
        end
        
        % Sets the CP tabs back to zero, the default state
        function changeCPBackToZero(app, tabGroupString)
            % Uses feval to evaluate parameter names
            tabGroup = eval(['app.' tabGroupString '_TabGroup']);
            
            % Deletes the current tabs
            delete(get(tabGroup, 'Children'));
            
            % Creates tabs for each element until num
            uitab(tabGroup, 'Title', 'Coupling Parameter Tabs');
        end
        
        function update_templates(app)
            % Updates the parameters dropdown menu
            [filepath, ~, ~] = fileparts(mfilename('fullpath'));
            cd(filepath);
            cd ../;
            paramFiles = dir('parameters-library/*.xlsx');
            app.param_templates = {};
            for file = paramFiles'
                [~, name, ~] = fileparts(file.name);
                app.param_templates{end+1} = name;
            end     
            app.loadParametersDropDown.Items = app.param_templates;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Displays warning message when the looping_param is the same as
        % any of the strings from the second list
        function warn = display_coupling_selection(app, CP_num, looping_param, coupling_params, disp_string)
            warn = 0;
            disp("Function called...")
            for i = 1:CP_num
                % str1 = looping_param;
                % str2 = coupling_params{i};
                % looping_param + coupling_params{param};
                if strcmp(looping_param,coupling_params{i})
                    warndlg("One of your coupling parameter is the same as the " + disp_string + " variable. Either " + ...
                        "change the independent variable or the coupling parameter.")
                    warn = 1;
                    return
                end
                
                for j = i+1:CP_num
                    if strcmp(coupling_params{i},coupling_params{j})
                        warndlg("Two of your coupling parameters are the same. Please choose different parameters or reduce " + ...
                            "number of coupling parameters by 1.");
                        warn = 1;
                        return
                    end
                end
            end
        end
        
        % Displays warning message when coupling parameter is out of its
        % bounds
         % function warn = display_coupling_out_of_bounds(app, coupling_values)
        function warn = display_coupling_out_of_bounds(app, CP_num, coupling_params, coupling_indices, coupling_looping_values)
            warn = 0; 
            for param = 1:CP_num
                 coupling_param_x_limits = getEditFieldLimits(app, coupling_params{param}, coupling_indices(param));
                 minVal = min([coupling_looping_values{param}(1) coupling_looping_values{param}(end)]);
                 maxVal = max([coupling_looping_values{param}(1) coupling_looping_values{param}(end)]);
                 if ((minVal < coupling_param_x_limits(1)) | ...
                     (minVal > coupling_param_x_limits(2)) | ...
                     (maxVal < coupling_param_x_limits(1)) | ...
                     (maxVal > coupling_param_x_limits(2)))
                     warndlg("The coupling relationship results in an z_min or z_max value that is out of the bounds for the coupling quantity you have chosen." ...
                         + newline + "Please change the relationship or the bound values.")
                     warn = 1
                 end
             end
        end
        
        % Displays log warning
        function warn = log_space_warning(app, logObj, minObj, maxObj, varString1, varString2)
            warn = 0
            if strcmp(logObj.Value, 'log')
                if ((minObj.Value <= 0) || (maxObj <= 0))
                    warndlg("One or both of the values you've entered for the " + varString1 + ...
                        " looping range is non-positive, " + ...
                        "AND you have chosen a logspace " + varString2 + ". " + newline +...
                        "Either change to linear spacing or use a positive bound.", 'Warning')
                    warn = 1
                end
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function heatmap(app)
            
            warn = log_space_warning(app, app.x_log_space, app.xmin, app.xmax, "xmin/xmax", "x-axis");
            warn = warn + log_space_warning(app, app.y_log_space, app.ymin, app.ymax, "ymin/ymax", "y-axis");
            if warn > 0
                return
            end
            
            % Warning when non of the output parameter is selected
            if ~(app.y_maxCheckBox.Value || app.y_unlatchCheckBox.Value || app.t_LCheckBox.Value || ...
                    app.v_toCheckBox.Value || app.P_maxCheckBox.Value || app.t_toCheckBox.Value || app.KE_maxCheckBox.Value)
                warndlg('You must select at least one output option', 'Warning');
                return
            end
            
            % Determines resolution of heatplots
            N=app.n.Value;
            
            % Output directory initialization
            if app.savesolutionCheckBox.Value
                output_directory = create_output_directory();
            end
            
            %% plot parameters
            
            % Determines the edit-field index and value of looping_param_x
            looping_param_x = app.IV1DropDown.Value;
            [~, xindex] = ismember(looping_param_x, app.IV1DropDown.Items);
            xefIndex = ddIndexToefIndex(app, xindex, looping_param_x);
            looping_param_x_value = getEditFieldVal(app, looping_param_x, xefIndex);

            % Finds the edit-field value of each of the coupling
            % parameters for the looping variable x
            CPX_num = app.HMCPX_num.Value;
            CPX_names = cell(1, CPX_num);
            CPX_efIndices = zeros(1, CPX_num);
            CPX_values = zeros(1, CPX_num);
            CPX_relationships = cell(1,CPX_num);
            
            % Loops through each of the coupling parameters
            for i = 1:CPX_num
                CPX_names{i} = app.HMCPXDropDowns(i).Value;
                CPX_relationships{i} = app.HMCPXRelationships(i).Value;
                
                [~, ddIndex] = ismember(app.HMCPXDropDowns(i).Value, app.HMCPXDropDowns(i).Items);
                CPX_efIndices(i) = ddIndexToefIndex(app, ddIndex, CPX_names(i));
                CPX_values(i) = getEditFieldVal(app, CPX_names(i), CPX_efIndices(i));
            end
            
            % Determines the edit-field index and value of looping_param_y
            looping_param_y = app.IV2DropDown.Value;
            [~, yindex] = ismember(looping_param_y, app.IV2DropDown.Items);
            yefIndex = ddIndexToefIndex(app, yindex, looping_param_y);
            looping_param_y_value = getEditFieldVal(app, looping_param_y, yefIndex);

            % Finds the edit-field value of each of the coupling parameters
            % for the looping variable y
            CPY_num = app.HMCPY_num.Value;
            CPY_names = cell(1, CPY_num);
            CPY_efIndices = zeros(1, CPY_num);
            CPY_values = zeros(1, CPY_num);
            CPY_relationships = cell(1,CPY_num);
            
            % Loops through each of the coupling parameters
            for i = 1:CPY_num
                CPY_names{i} = app.HMCPYDropDowns(i).Value;
                CPY_relationships{i} = app.HMCPYRelationships(i).Value;
                
                [~, ddIndex] = ismember(app.HMCPYDropDowns(i).Value, app.HMCPYDropDowns(i).Items);
                CPY_efIndices(i) = ddIndexToefIndex(app, ddIndex, CPY_names(i));
                CPY_values(i) = getEditFieldVal(app, CPY_names(i), CPY_efIndices(i));
            end
            
            warn = display_coupling_selection(app, CPX_num, looping_param_x, CPX_names, "x-axis");
            warn = warn + display_coupling_selection(app, CPX_num, looping_param_y, CPX_names, "y-axis");
            warn = warn + display_coupling_selection(app, CPY_num, looping_param_y, CPY_names, "y-axis");
            warn = warn + display_coupling_selection(app, CPY_num, looping_param_x, CPY_names, "x-axis");
            if warn > 0
                return
            end
            
            % Limit out of bound error
            looping_param_x_limits = getEditFieldLimits(app, looping_param_x, xefIndex);
            looping_param_y_limits = getEditFieldLimits(app, looping_param_y, yefIndex);
            if ((app.xmin.Value < looping_param_x_limits(1)) | ...
                (app.xmin.Value > looping_param_x_limits(2)) | ...
                (app.xmax.Value < looping_param_x_limits(1)) | ...
                (app.xmax.Value > looping_param_x_limits(2)))     
                warndlg("You have chosen an xmin or xmax value that is out of the bounds for the looping quantity you have selected." + newline + ...
                    "i.e. you have chosen to loop through negative masses, or negative coefficients of friction, or something similarly bad.", ...
                    'Warning')
                return
            end
            if ((app.ymin.Value < looping_param_y_limits(1)) | ...
                (app.ymin.Value > looping_param_y_limits(2)) | ...
                (app.ymax.Value < looping_param_y_limits(1)) | ...
                (app.ymax.Value > looping_param_y_limits(2)))     
                warndlg("You have chosen an ymin or ymax value that is out of the bounds for the looping quantity you have selected." + newline + ...
                    "i.e. you have chosen to loop through negative masses, or negative coefficients of friction, or something similarly bad.", ...
                    'Warning')
                return
            end


            % setting x axis on the plot
            xname = app.IV1DropDown.Value;
            if strcmp(app.x_log_space.Value,'log')
                xrange = [log10(app.xmin.Value) log10(app.xmax.Value)];
                looping_value_x = logspace(xrange(1),xrange(2),N);
            else
                xrange = [app.xmin.Value app.xmax.Value];
                looping_value_x = linspace(xrange(1),xrange(2),N);
            end
            
            % Update the x axis coupling relationship according to user input
            CPX_looping_value = cell(1,CPX_num);
            CPX_relationship_functions = cell(1, CPX_num);
            
            % Iterate through each parameter
            try
                for index = 1:CPX_num
                    CPX_relationships{index};
                    CPX_relationship_functions{index} = str2func(['@(x)' CPX_relationships{index}]);
                    CPX_looping_value{index} = arrayfun(CPX_relationship_functions{index}, looping_value_x);
                end
            catch
                % code here to display error
                warndlg('Please enter the coupling relationships using MATLAB syntax.','Error');
                return
            end
            
            %setting y axis value on plot
            yname = app.IV2DropDown.Value;
            if strcmp(app.y_log_space.Value,'log')
                yrange = [log10(app.ymin.Value) log10(app.ymax.Value)];
                looping_value_y = logspace(yrange(1),yrange(2),N);
            else
                yrange = [app.ymin.Value app.ymax.Value];
                looping_value_y = linspace(yrange(1),yrange(2),N);
            end
            
            % Update the y axis coupling relationship according to user input
            CPY_looping_value = cell(1,CPY_num);
            CPY_relationship_functions = cell(1, CPY_num);
            
            % Iterate through each parameter
            try
                for index = 1:CPY_num
                    CPY_relationships{index};
                    CPY_relationship_functions{index} = str2func(['@(y)' CPY_relationships{index}]);
                    CPY_looping_value{index} = arrayfun(CPY_relationship_functions{index}, looping_value_y);
                end
            catch
                % code here to display error
                warndlg('Please enter the coupling relationships using MATLAB syntax.','Error');
                return
            end
                        
            warn = display_coupling_out_of_bounds(app, CPX_num, CPX_names, CPX_efIndices, CPX_looping_value);
            warn = warn + display_coupling_out_of_bounds(app, CPY_num, CPY_names, CPY_efIndices, CPY_looping_value);
            if warn > 0
                return
            end
            
            % initializing waitbar
            f = waitbar(0,'Please wait...');
            load_bar_value = 1/N;
            load_bar_increment = 1/N;
            
            % add things to metrics
            metrics = {};
            metrics_names = {};
            metrics_labels = {};
            if app.y_maxCheckBox.Value
                metrics{end+1} = 'ymax';
                metrics_names{end+1} = 'ymax';
                metrics_labels{end+1} = '$y_{\textrm{max}}$';
            end
            if app.y_unlatchCheckBox.Value
                metrics{end+1} = 'yunlatch';
                metrics_names{end+1} = 'yunlatch';
                metrics_labels{end+1} = '$y_{\textrm{unlatch}}$';
            end
            if app.t_LCheckBox.Value
                metrics{end+1} = 'tL';
                metrics_names{end+1} = 'tL';
                metrics_labels{end+1} = '$t_L$';
            end
            if app.v_toCheckBox.Value
                metrics{end+1} = 'vto';
                metrics_names{end+1} = 'vto';
                metrics_labels{end+1} = '$v_{\textrm{to}}$';
            end
            if app.P_maxCheckBox.Value
                metrics{end+1} = 'Pmax';
                metrics_names{end+1} = 'Pmax';
                metrics_labels{end+1} = '$P_{\textrm{max}}$';
            end
            if app.t_toCheckBox.Value
                metrics{end+1} = 'tto';
                metrics_names{end+1} = 'tto';
                metrics_labels{end+1} = '$t_{\textrm{to}}$';
            end
            if app.a_maxCheckBox.Value
                metrics{end+1} = 'amax';
                metrics_names{end+1} = 'amax';
                metrics_labels{end+1} = '$a_{\textrm{max}}$';
            end
            if app.KE_maxCheckBox.Value
                metrics{end+1} = 'KEmax';
                metrics_names{end+1} = 'KEmax';
                metrics_labels{end+1} = '$KE_{\textrm{max}}$';
            end
            
            if isempty(metrics)
                warndlg('You must pick at least one output option','Error');
                return
            end
            
            metrics_dict = containers.Map(metrics_names,metrics_labels);           
            
            % ensures that both independent variables that we are varying over are not the same
            if (strcmp(looping_param_y,looping_param_x))
                errordlg('The two independent variables must be different. Please change them and retry.','Error');
                return
            end
            
            % outval: a cell array, each element is a list of heatmap parameters
            outval = cell(1, length(metrics));
            for ii=1:length(metrics)
                outval{ii}=zeros(N);
            end
            
            for i=1:N % iterate over y-axis-variable of plot
                for j=1:N % iterate over x-axis-variable of plot       
                    
                    % Updates the looping and coupling values of x and y
                    % parameters
                    changeEditField(app, looping_param_x, xefIndex, looping_value_x(j));
                    changeEditField(app, looping_param_y, yefIndex, looping_value_y(i));                    
                    
                    for param = 1:CPX_num
                        changeEditField(app, CPX_names{param}, CPX_efIndices(param), CPX_looping_value{param}(j));
                    end
                    for param = 1:CPY_num
                        changeEditField(app, CPY_names{param}, CPY_efIndices(param), CPY_looping_value{param}(i));
                    end
                    
                    %% initializing LaMSA component structs
                    [spring, loading_motor, unlatching_motor, load, latch] = initialize_components(app);
                    
                    % calling solve model
                    if app.savesolutionCheckBox.Value
                        [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring,output_directory);
                    else
                        [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring);
                    end
                    
                    % warning messages
                    [warnMsg, warnId] = lastwarn;
                    if (strcmp(warnMsg,'Loading failed. Does not fall within latching distance conditions.'))
                        if ~exist('loading_failed','var')
                            loading_failed = warndlg('Loading failed. Does not fall within latching distance conditions.','Warning');
                        end
                    end
                    if (strcmp(warnMsg,'Latch gets stuck!'))
                        if ~exist('latch_stuck','var')
                            latch_stuck = warndlg('Latch gets stuck!','Warning');
                        end
                    end
                    if (strcmp(warnMsg,"The latch's initial velocity and acceleration are both zero."))
                        if ~exist('latch_vi_a_zero','var')
                            latch_vi_a_zero = warndlg("The latch's initial velocity and acceleration are both zero.",'Warning');
                        end
                    end
                   

                    met_dict=get_metrics(sol,transition_times,load,metrics);
                    for ii=1:length(metrics)
                        outval{ii}(i,j)=met_dict(metrics{ii});
                    end
                    
                    % Run direct actuation model if MakeLaMSAZonePlotCheckBox
                    % is selected
                    if (app.MakeLaMSAZonePlotCheckBox.Value)
                        [direct_sol, direct_transition_times] = solve_direct_actuation(loading_motor,load);
                        direct_met_dict=get_metrics(direct_sol,direct_transition_times,load,metrics);
                        for ii=1:length(metrics)
                            direct_outval{ii}(i,j)=direct_met_dict(metrics{ii});
                        end
                    end
                    
                end
                disp(['row ' num2str(i) ' of ' num2str(N)]);
                load_bar_value = load_bar_value + load_bar_increment;
                waitbar(load_bar_value,f,'Processing...');
            end
            
            close(f)
            
            % Changes all looping fields back to the original input
            changeEditField(app, looping_param_x, xefIndex, looping_param_x_value);
            changeEditField(app, looping_param_y, yefIndex, looping_param_y_value);
            for param = 1:CPX_num
                changeEditField(app, CPX_names{param}, CPX_efIndices(param), CPX_values(param));
            end
            for param = 1:CPY_num
                changeEditField(app, CPY_names{param}, CPY_efIndices(param), CPY_values(param));
            end
            
            % plot output
            fh = figure('Name','LaMSA Heatmaps');
            fh.WindowState = 'maximized';
            subplot_rows = floor(sqrt(length(metrics)));
            subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
            
            % Traverse through each of the selected boxes (heatmap
            % parameters)
            for ii=1:length(metrics)
                subplot(subplot_rows,subplot_cols,ii);
                
                % use an imageHandle so that you can click on the heatplot 
                % and have a kinematics plot show up
                imageHandle = imagesc(xrange,yrange,outval{ii});
                set(gca,'YDir','normal');
                xlabel(xname,'Interpreter', 'Latex');
                ylabel(yname, 'Interpreter', 'Latex');
                
                c = colorbar;
                % make "better performance" a darker blue
                if (strcmp(metrics_names{ii},'tto')) 
                    colormap(gca,flipud(linspecer('blue'))); % for tto - smaller means better, so flip
                else
                    colormap(gca,linspecer('blue')); % otherwise, higher is better
                end
                c.Label.Interpreter = 'Latex';
                set(c,'TickLabelInterpreter','Latex');
                c.Label.String = metrics_dict(metrics_names{ii});
                
                
                % makes the x and y axes in log scale if selected
                set(gca,'TickLabelInterpreter','Latex');
                
                xtick = get(gca,'XTick');
                ytick = get(gca,'YTick');
                xticklabel = get(gca,'XTickLabel');
                yticklabel = get(gca,'YTickLabel');
                if strcmp(app.x_log_space.Value,'log')
                    for i=1:length(xticks)
                        xticklabel{i} = strcat('$10^{',num2str(xtick(i)),'}$');
                    end
                end
                set(gca,'XTickLabel',xticklabel);
                set(gca,'XTick',xtick);
                if strcmp(app.y_log_space.Value,'log')
                    for j=1:length(yticks)
                        yticklabel{j} = strcat('$10^{',num2str(ytick(j)),'}$');
                    end
                end
                set(gca,'YTickLabel',yticklabel);
                set(gca,'YTick',ytick);
                set(imageHandle,'ButtonDownFcn',{@showKinematics, looping_param_x, looping_param_y, app});
                
            end
            
            % plot direct actuation output
            if (app.MakeLaMSAZonePlotCheckBox.Value)

                fh = figure('Name','Direct Actuation Heatmaps');
                fh.WindowState = 'maximized';
                subplot_rows = floor(sqrt(length(metrics)));
                subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
                for ii=1:length(metrics)
                    subplot(subplot_rows,subplot_cols,ii);
                    
                    imagesc(xrange,yrange,direct_outval{ii});
                    set(gca,'YDir','normal');
                    xlabel(xname,'Interpreter', 'Latex');
                    ylabel(yname, 'Interpreter', 'Latex');
                    
                    c = colorbar;
                    % make "better performance" a darker red
                    if (strcmp(metrics_names{ii},'tto')) 
                        colormap(gca,flipud(linspecer('red'))); % for tto - smaller means better, so flip
                    else
                        colormap(gca,linspecer('red')); % otherwise, higher is better
                    end
                 
                    c.Label.Interpreter = 'Latex';
                    set(c,'TickLabelInterpreter','Latex');
                    c.Label.String = metrics_dict(metrics_names{ii});
                    
                    
                    % makes the x and y axes in log scale
                    set(gca,'TickLabelInterpreter','Latex');
                    
                    xtick = get(gca,'XTick');
                    ytick = get(gca,'YTick');
                    xticklabel = get(gca,'XTickLabel');
                    yticklabel = get(gca,'YTickLabel');
                    if strcmp(app.x_log_space.Value,'log')
                        for i=1:length(xticks)
                            xticklabel{i} = strcat('$10^{',num2str(xtick(i)),'}$');
                        end
                    end
                    set(gca,'XTickLabel',xticklabel);
                    set(gca,'XTick',xtick);
                    if strcmp(app.y_log_space.Value,'log')
                        for j=1:length(yticks)
                            yticklabel{j} = strcat('$10^{',num2str(ytick(j)),'}$');
                        end
                    end
                    set(gca,'YTickLabel',yticklabel);
                    set(gca,'YTick',ytick);
                end
                
                
                fh = figure('Name','LaMSA Zone Heatmaps');
                fh.WindowState = 'maximized';
                subplot_rows = floor(sqrt(length(metrics)));
                subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
                for ii=1:length(metrics)
                    subplot(subplot_rows,subplot_cols,ii);
                    
                    % calculate LaMSA Zone ratio
                    if (strcmp(metrics_names{ii},'tto')) 
                        LaMSAval = direct_outval{ii}./outval{ii};
                    else
                        LaMSAval = outval{ii}./direct_outval{ii};
                    end
            
                    max_output = max(2,max(LaMSAval,[],'all'));
                    min_output = min(0.5,min(LaMSAval,[],'all'));
                    
                    % map output onto the range 0 to 2 for the colormap to
                    % have an even spread between LaMSA and non-LaMSA
                    scaledval = LaMSAval;
                    idx = find(scaledval>1);
                    scaledval(idx) = (scaledval(idx)-1)/(max_output-1) + 1;
                    idx = find(scaledval<1);
                    scaledval(idx) = 1 - (1-scaledval(idx))/(1-min_output);
                    
                    % dispay scaled value of LaMSA ratio
                    imagesc(xrange,yrange,scaledval);
                    set(gca,'YDir','normal');
                    xlabel(xname,'Interpreter', 'Latex', 'FontSize', 16);
                    ylabel(yname, 'Interpreter', 'Latex', 'FontSize', 16);
                    
                    c = colorbar;
                    % make a LaMSA Zone colormap going red-->white-->blue
                    rval = [.42 0.03 0.13]; % starting red value
                    wval = [ 1 1 1];
                    bval = [0.03 0.18 .42]; % ending blue value
                    colmap_res = 100; % number of colors in each range of the colormap
                    bcolmap = [linspace(rval(1),wval(1),colmap_res)',linspace(rval(2),wval(2),colmap_res)',linspace(rval(3),wval(3),colmap_res)'];
                    rcolmap = [linspace(wval(1),bval(1),colmap_res)',linspace(wval(2),bval(2),colmap_res)',linspace(wval(3),bval(3),colmap_res)'];
                    colmap = [bcolmap;rcolmap];
                    colormap(gca,colmap);
                    caxis([0,2]);
                    set(c,'YTick',[0,1,2]);
                    set(c,'YTickLabel',{strcat('1/',num2str(1/min_output,'%.0f')),'1',num2str(max_output,'%.0f')});
                    c.Label.Interpreter = 'Latex';
                    set(c,'TickLabelInterpreter','Latex');
                    c.Label.String = strcat("LaMSA Zone Ratio for ", metrics_dict(metrics_names{ii}));
                    
                    
                    % makes the x and y axes in log scale
                    set(gca,'TickLabelInterpreter','Latex');
                    
                    xtick = get(gca,'XTick');
                    ytick = get(gca,'YTick');
                    xticklabel = get(gca,'XTickLabel');
                    yticklabel = get(gca,'YTickLabel');
                    if strcmp(app.x_log_space.Value,'log')
                        for i=1:length(xticks)
                            xticklabel{i} = strcat('$10^{',num2str(xtick(i)),'}$');
                        end
                    end
                    set(gca,'XTickLabel',xticklabel);
                    set(gca,'XTick',xtick);
                    if strcmp(app.y_log_space.Value,'log')
                        for j=1:length(yticks)
                            yticklabel{j} = strcat('$10^{',num2str(ytick(j)),'}$');
                        end
                    end
                    set(gca,'YTickLabel',yticklabel);
                    set(gca,'YTick',ytick);
                end
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function one_D_plot(app)
            % Issues warning when none of the checkboxes are selected
            if ~(app.OD_y_maxCheckBox.Value || app.OD_y_unlatchCheckBox.Value || app.OD_t_LCheckBox.Value || ...
                    app.OD_v_toCheckBox.Value || app.OD_P_maxCheckBox.Value || app.OD_t_toCheckBox.Value || app.OD_KE_maxCheckBox.Value)
                warndlg('You must select at least one output option', 'Warning');
                return
            end
            
            % Issues warning with problems in the log scale
            if strcmp(app.OD_x_log_space.Value,'log')
                if ((app.OD_xmin.Value <= 0) |(app.OD_xmax.Value <= 0))
                    warndlg("One or both of the values you've entered for the xmin/xmax " + ...
                        "looping range is non-positive, " + ...
                        "AND you have chosen a logspace x-axis. " + newline +...
                        "Either change to linear spacing or use a positive bound.", 'Warning')
                    return
                end
            end
            
            % determines resolution of 1-D Plot
            N=app.OD_n.Value; 
            
            % output directory initialization
            if app.savesolutionCheckBox.Value
                output_directory = create_output_directory();
            end
            
            %% plot parameters
            
            % Update looping parameter
            looping_param_x = app.OD_IV1DropDown.Value;
            
            % Gets the corresponding edit-field value by first finding the
            % edit-field index of looping_param_x
            [~, index] = ismember(looping_param_x, app.OD_IV1DropDown.Items);
            efIndex = ddIndexToefIndex(app, index, looping_param_x);
            looping_param_x_value = getEditFieldVal(app, looping_param_x, efIndex);
            
            % Finds the edit-field value of each of the coupling parameters
            CP_num = app.ODCP_num.Value;
            CP_names = cell(1, CP_num);
            CP_efIndices = zeros(1, CP_num);
            CP_values = zeros(1, CP_num);
            CP_relationships = cell(1,CP_num);
            
            % Loops through each of the coupling parameters
            for i = 1:CP_num
                CP_names{i} = app.ODCPDropDowns(i).Value;
                CP_relationships{i} = app.ODCPRelationships(i).Value;
                
                [~, ddIndex] = ismember(app.ODCPDropDowns(i).Value, app.ODCPDropDowns(i).Items);
                CP_efIndices(i) = ddIndexToefIndex(app, ddIndex, CP_names(i));
                CP_values(i) = getEditFieldVal(app, CP_names(i), CP_efIndices(i));
            end
            
            warn = display_coupling_selection(app, CP_num, looping_param_x, CP_names, "x-axis");
            if warn == 1
                return
            end

            % Issues warning message on looping_param_x limits
            looping_param_x_limits = getEditFieldLimits(app, looping_param_x, efIndex);
            if ((app.OD_xmin.Value < looping_param_x_limits(1)) | ...
                (app.OD_xmin.Value > looping_param_x_limits(2)) | ...
                (app.OD_xmax.Value < looping_param_x_limits(1)) | ...
                (app.OD_xmax.Value > looping_param_x_limits(2)))     
                warndlg("You have chosen an x_min or x_max value that is out of the bounds for the looping quantity you have selected." + newline + ...
                    "i.e. you have chosen to loop through negative masses, or negative coefficients of friction, or something similarly bad.", ...
                    'Warning')
                return
            end
            
            % setting x axis on the plot
            xname = app.OD_IV1DropDown.Value;
            if strcmp(app.OD_x_log_space.Value,'log')
                xrange = [log10(app.OD_xmin.Value) log10(app.OD_xmax.Value)];
                looping_value_x = logspace(xrange(1),xrange(2),N);
            else
                xrange = [app.OD_xmin.Value app.OD_xmax.Value];
                looping_value_x = linspace(xrange(1),xrange(2),N);
            end
            
            % Update the coupling relationship according to user input
            CP_looping_value = cell(1,CP_num);
            CP_relationship_functions = cell(1, CP_num);
            
            % Iterate through each parameter
            try
                for index = 1:CP_num
                    CP_relationships{index};
                    CP_relationship_functions{index} = str2func(['@(x)' CP_relationships{index}]);
                    CP_looping_value{index} = arrayfun(CP_relationship_functions{index}, looping_value_x);
                end
            catch
                % code here to display error
                warndlg('Please enter the coupling relationships using MATLAB syntax.','Error');
                return
            end
            
            warn = display_coupling_out_of_bounds(app, CP_num, CP_names, CP_efIndices, CP_looping_value);
            if warn == 1
                return
            end
            
            % initializing waitbar
            f = waitbar(0,'Please wait...');
            load_bar_value = 1/N;
            load_bar_increment = 1/N;
            
            
            % add things to metrics
            metrics = {};
            
            if app.OD_y_maxCheckBox.Value
                metrics{end+1} = 'ymax';
            end
            if app.OD_y_unlatchCheckBox.Value
                metrics{end+1} = 'yunlatch';
            end
            if app.OD_t_LCheckBox.Value
                metrics{end+1} = 'tL';
            end
            if app.OD_v_toCheckBox.Value
                metrics{end+1} = 'vto';
            end
            if app.OD_P_maxCheckBox.Value
                metrics{end+1} = 'Pmax';
            end
            if app.OD_t_toCheckBox.Value
                metrics{end+1} = 'tto';
            end
            if app.OD_a_maxCheckBox.Value
                metrics{end+1} = 'amax';
            end
            if app.OD_KE_maxCheckBox.Value
                metrics{end+1} = 'KEmax';
            end
            
            if isempty(metrics)
                warndlg('You must pick at least one output option','Error');
                return
            end
            
            % Creates a dictionary that maps the metrics names to labels
            metrics_names = {'ymax','yunlatch','tL','vto','Pmax','tto','amax','KEmax', 'KE_Ratio', 'unlatching_motor_work_done'};
            metrics_labels = {'$y_{\textrm{max}}$','$y_{\textrm{unlatch}}$','$t_L$','$v_{\textrm{to}}$','$P_{\textrm{max}}$','$t_{\textrm{to}}$','$a_\textrm{max}$','$KE_{\textrm{max}}$', 'KE Ratio', 'unlatching motor work done'};
            metrics_dict = containers.Map(metrics_names,metrics_labels);
            
            % Outval: a cell array, each element is a list of y values
            outval = cell(1, length(metrics));
            for ii=1:length(metrics)
                outval{ii} = zeros(size(looping_value_x));
            end
            
            % Iterates through N number of data points
            for i=1:N
                
                % update looping value and coupling parameter values
                changeEditField(app, looping_param_x, efIndex, looping_value_x(i));
                for param = 1:CP_num
                    changeEditField(app, CP_names{param}, CP_efIndices(param), CP_looping_value{param}(i))
                end
                
                % coupling for mass used in the paper: app.load_mass_mass.Value = (app.lm_hill_motor_Fmax.Value/86.2)^1.5;
                %% initializing LaMSA component structs
                [spring, loading_motor, unlatching_motor, load, latch] = initialize_components(app);
                
                % calling solve model
                if app.savesolutionCheckBox.Value;;
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring,output_directory);
                else
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring);
                end
                
                
                % warning messages
                [warnMsg, warnId] = lastwarn;
                if (strcmp(warnMsg,'Loading failed. Does not fall within latching distance conditions.'))
                    if ~exist('loading_failed','var')
                        loading_failed = warndlg('Loading failed. Does not fall within latching distance conditions.','Warning');
                    end
                end
                if (strcmp(warnMsg,'Latch gets stuck!'))
                    if ~exist('latch_stuck','var')
                        latch_stuck = warndlg('Latch gets stuck!','Warning');
                    end
                end
                if (strcmp(warnMsg,"The latch's initial velocity and acceleration are both zero."))
                    if ~exist('latch_vi_a_zero','var')
                        latch_vi_a_zero = warndlg("The latch's initial velocity and acceleration are both zero.",'Warning');
                    end
                end
                
                met_dict=get_metrics(sol,transition_times,load,metrics);
                for ii=1:length(metrics)
                    outval{ii}(i)=met_dict(metrics{ii});
                end
                
                % Run direct actuation model if ComparetoDirectActuationCheckBox
                % is selected
                if (app.ComparetoDirectActuationCheckBox.Value)
                    [direct_sol, direct_transition_times] = solve_direct_actuation(loading_motor,load);
                    direct_met_dict=get_metrics(direct_sol,direct_transition_times,load,metrics);
                    for ii=1:length(metrics)
                        direct_outval{ii}(i)=direct_met_dict(metrics{ii});
                    end
                end
                
                disp(['point ' num2str(i) ' of ' num2str(N)]);
                load_bar_value = load_bar_value + load_bar_increment;
                waitbar(load_bar_value,f,'Processing...');
            end

            close(f);

            % Change the edit field values back to the original values
            % before looping
            changeEditField(app, looping_param_x, efIndex, looping_param_x_value);
            for param = 1:CP_num
                changeEditField(app, CP_names{param}, CP_efIndices(param), CP_values(param));
            end
            
            % plot output
            fh = figure('Name','1D Plot');
            fh.WindowState = 'maximized';
            subplot_rows = floor(sqrt(length(metrics)));
            subplot_cols = ceil(length(metrics)/floor(sqrt(length(metrics))));
            for ii=1:length(metrics)
                subplot(subplot_rows,subplot_cols,ii);
                plot(looping_value_x,outval{ii},'-b','LineWidth',1.3); 
                if (app.ComparetoDirectActuationCheckBox.Value)
                    hold on;
                    plot(looping_value_x,direct_outval{ii},'-r','LineWidth',1.3);
                    legend({'LaMSA','Direct Actuation'},'Location','Best');
                end
                xlabel(xname, 'Interpreter', 'Latex','FontSize',16);
                ylabel(metrics_dict(metrics{ii}), 'Interpreter', 'Latex','FontSize', 16);
                ylim_range = max(outval{ii})-min(outval{ii});
                if (ylim_range == 0)
                    ylim_range = abs(max(outval{ii}));
                    if ~(ylim_range == 0)
                        ylim([min([0, min(outval{ii})-0.05*ylim_range]) max([0,max(outval{ii})+0.05*ylim_range]) ])
                    end
                else
                    ylim([min(outval{ii})-0.05*ylim_range max(outval{ii})+0.05*ylim_range])
                end
                set(gca,'TickLabelInterpreter','latex')
                % makes the x in log scale if needed
                if strcmp(app.OD_x_log_space.Value,'log')
                    set(gca,'XScale','log')
                end 
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function kinematics(app)
            if ~(app.loadkinematicsCheckBox.Value || app.latchkinematicsCheckBox.Value || app.forcedisp.Value)
                warndlg('You must select at least one kinematics option', 'Warning');
                return
            end
            
            f = waitbar(0,'Please wait...');
            load_bar_value = 1/4;
            load_bar_increment = 1/4;
            
            % initializing output directory
            if app.savesolutionCheckBox.Value
                output_directory = create_output_directory();
            end
            
            %% initializing LaMSA component structs
            [spring, loading_motor, unlatching_motor, load, latch] = initialize_components(app);
            
            if app.savesolutionCheckBox.Value
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring,output_directory);
                else
                    [sol,transition_times] = solve_model(loading_motor,unlatching_motor,load,latch,spring);
            end
            
            columntitles=["Time", "y postition", "y velocity", "x position", "x velocity", "normal force on latch x", ...
                "normal force on load y", "frictional force on latch x", ...
                "frictional force on load y", "spring force", ...
                "unlatching motor force into"];
            
            close(f)
            
            % latch kinematics
            if (app.latchkinematicsCheckBox.Value)    
                figure('Name','Latch Kinematics')
                for i = 4:5
                    subplot(1,3,i-3)
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(sol(:,1),sol(:,i));
                    hold off
                    title(columntitles(i),"Interpreter","latex");
                    ylabel(columntitles(i),"Interpreter","latex");
                    xlabel(columntitles(1),"Interpreter","latex");
                end
                
                for i = [6 8 11]
                    subplot(1,3,3)
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(sol(:,1),sol(:,i),"DisplayName",columntitles(i))
                end
                title("Latch Force Components","Interpreter","latex");
                ylabel("Force","Interpreter","latex");
                xlabel(columntitles(1),"Interpreter","latex");
                legend("show")
                hold off
            end
            
            % load kinematics
            if (app.loadkinematicsCheckBox.Value)
                figure('Name','Load Kinematics')
                for i = 2:3
                    subplot(1,3,i-1)
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(sol(:,1),sol(:,i));
                    hold off
                    title(columntitles(i),"Interpreter","latex");
                    ylabel(columntitles(i),"Interpreter","latex");
                    xlabel(columntitles(1),"Interpreter","latex");
                end
                
                for i = [7 9 10]
                    subplot(1,3,3)
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(sol(:,1),sol(:,i),"DisplayName",columntitles(i))
                end
                title("Load Force Components","Interpreter","latex");
                ylabel("Force","Interpreter","latex");
                xlabel(columntitles(1),"Interpreter","latex");
                legend("show")
                hold off
            end    
            
            % force displacement curves for motor and spring
            if (app.forcedisp.Value)    
                figure('Name','Motor and Spring Force Displacement Curves')
                if (loading_motor.range == Inf)
                    y_max = 5*sol(1,2);
                else
                    y_max = -loading_motor.range;
                end
                y_range = linspace(0,y_max,100);
                motor_force_array = [];
                spring_force_array = [];
                for i = 1:length(y_range)
                    motor_force_array(end+1) = loading_motor.Force(Inf,[-y_range(i),0]);
                    spring_force_array(end+1) = spring.Force(Inf,[y_range(i),0]);
                end
                force_arrays = {spring_force_array motor_force_array};
                for i = 1:2
                    box on
                    set(gca,'TickLabelInterpreter','latex')
                    hold on
                    plot(abs(y_range),force_arrays{i});
                end
                plot([-sol(1,2),-sol(1,2)],[0,spring.Force(Inf,[sol(1,2),0])],"--k")
                plot(-sol(1,2),spring.Force(Inf,[sol(1,2),0]),'ok')
                title('Force Displacement Curve',"Interpreter","latex");
                ylabel('Force',"Interpreter","latex");
                xlabel('Displacement',"Interpreter","latex");
                legend('spring','motor','loaded displacement')
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function sensitivity(app)
            
            f = waitbar(1/4,'Please wait...');
            load_bar_value = 1/4;
            load_bar_increment = 1/4;
            
            selected_params = app.sensitivity_vars.Value;
            [~, selected_indices] = ismember(selected_params, app.sensitivity_vars.Items);
            
            for i = 1:length(selected_indices)
                selected_indices(i) = ddIndexToefIndex(app, selected_indices(i), selected_params(i));
            end
            x0_index = 1;
            disp(selected_indices);
            
            %%%% X0 SETUP %%%%
            x0 = zeros(1, length(selected_indices));
            for ii = 1:length(selected_indices)
                val = getEditFieldVal(app, selected_params(ii), selected_indices(ii));
                x0(ii) = val;
            end
            x0 = x0
            
            %%%% LOAD SETUP %%%%
            load_name = app.MassTabGroup.SelectedTab.Title;
            load_str = ['load_fun = @(x0)' char(load_name) '('];
            ind = app.MassParamIndices(load_name);
            for ii = ind(1):ind(2)
                if (ismember(ii, selected_indices))
                    load_str = [load_str 'x0(' num2str(x0_index) '), '];
                    x0_index = x0_index + 1;
                else
                    val = app.MassEditFields(ii).Value;
                    load_str = [load_str num2str(val) ', '];
                end
            end
            load_str = [load_str(1:end-2) ')'];
            
            %%%% LATCH SETUP %%%%
            latch_name = app.LatchTabGroup.SelectedTab.Title;
            latch_str = ['latch_fun = @(x0)' char(latch_name) '('];
            ind = app.LatchParamIndices(latch_name);
            for ii = ind(1):ind(2)
                if (ismember(ii, selected_indices))
                    latch_str = [latch_str 'x0(' num2str(x0_index) '), '];
                    x0_index = x0_index + 1;
                else
                    val = app.LatchEditFields(ii).Value;
                    latch_str = [latch_str num2str(val) ', '];
                end
            end
            latch_str = [latch_str(1:end-2) ')'];
            
            %%%% SPRING SETUP %%%%
            spring_name = app.SpringTabGroup.SelectedTab.Title;
            spring_str = ['spring_fun = @(x0)' char(spring_name) '('];
            ind = app.SpringParamIndices(spring_name);
            for ii = ind(1):ind(2)
                if (ismember(ii, selected_indices))
                    spring_str = [spring_str 'x0(' num2str(x0_index) '), '];
                    x0_index = x0_index + 1;
                else
                    val = app.SpringEditFields(ii).Value;
                    spring_str = [spring_str num2str(val) ', '];
                end
            end
            spring_str = [spring_str(1:end-2) ')'];
            
            %%%% LOADING MOTOR SETUP %%%%
            lm_name = app.LoadingMotorTabGroup.SelectedTab.Title;
            lm_str = ['lm_fun = @(x0)' char(lm_name) '('];
            ind = app.LoadingMotorParamIndices(lm_name);
            for ii = ind(1):ind(2)
                if (ismember(ii, selected_indices))
                    lm_str = [lm_str 'x0(' num2str(x0_index) '), '];
                    x0_index = x0_index + 1;
                else
                    val = app.LoadingMotorEditFields(ii).Value;
                    lm_str = [lm_str num2str(val) ', '];
                end
            end
            lm_str = [lm_str(1:end-2) ')'];
            
            %%%% UNLATCHING MOTOR SETUP %%%%
            um_name = app.UnlatchingMotorTabGroup.SelectedTab.Title;
            um_str = ['um_fun = @(x0)' char(um_name) '('];
            ind = app.UnlatchingMotorParamIndices(um_name);
            for ii = ind(1):ind(2)
                if (ismember(ii, selected_indices))
                    um_str = [um_str 'x0(' num2str(x0_index) '), '];
                    x0_index = x0_index + 1;
                else
                    val = app.UnlatchingMotorEditFields(ii).Value;
                    um_str = [um_str num2str(val) ', '];
                end
            end
            
            um_str = [um_str(1:end-2) ')'];
            
            eval(load_str);
            eval(latch_str);
            eval(spring_str);
            eval(lm_str);
            eval(um_str);
            
            load_bar_value = load_bar_value + load_bar_increment;
            waitbar(load_bar_value,f,'Processing...');
            
            [combos,var_list] = sensitivity_analysis(lm_fun,um_fun,load_fun,latch_fun,spring_fun,x0,app.MetricButtonGroup.SelectedObject.Text,selected_params);
            commandwindow;
            disp(' ');
            disp('----------------------');
            disp('Sensitivity Analysis');
            disp('----------------------');
            disp(' ');
            column_labels = strings([1, size(combos,2)]);
            for i=1:length(column_labels)
                column_labels(i) = "combo #"+num2str(i);
            end
            combo_table = array2table(combos,'RowNames',selected_params,'VariableNames',column_labels);
            disp('Most Sensitive Combinations of Parameters')
            disp(combo_table);
            disp('Most Sensitive Parameter Axes')
            for i=1:length(var_list)
                disp(var_list(i));
            end
            close(f)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Constants for formatting tabs
            yPos = 15;
            xPos = 140;
            colSpacing = 250;
            labelSpacing = 120;
            width = 80;
            height = 22;
            
            % navigate from the current (app) file to components-library
            [filepath, ~, ~] = fileparts(mfilename('fullpath'));
            cd(filepath);
            filepath = filepath + "/..";
            addpath(genpath(fullfile(filepath)));
            cd ../;
            cd components-library;
            
            %%%%%%%%%%%%%%  LOAD MASS TABGROUP INITIALIZATION  %%%%%%%%%%%%
            
            % Delete the default tab groups
            massFiles = dir('load-masses/Subclasses/*.m');
            delete(get(app.MassTabGroup, 'Children'));
            
            % Starting at ind = 1, start fetching the load mass component files
            ind = 1;
            for file = massFiles'
                [~, name, ~] = fileparts(file.name);
                tab = uitab(app.MassTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.MassParamIndices(name) = [ind, ind + length(params(1,:)) - 1];
                app.MassParamMap(name) = params;
                for ii = 1:length(params(1,:))
                    paramName = params(1,ii);
                    defaultVal = str2double(params(2,ii));
                    limits = [str2double(params(3,ii)) str2double(params(4,ii))];
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.MassEditFields(ind) = uieditfield(tab, 'numeric');
                    app.MassEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.MassEditFields(ind).Value = defaultVal;
                    app.MassEditFields(ind).Limits = limits;
                    app.MassLabels(ind) = uilabel(tab);
                    app.MassLabels(ind).Text = paramName;
                    app.MassLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                if isempty(app.load_vars)
                    app.load_vars = "load " + params(1,:);
                end
                tab.BackgroundColor = [0.9 0.8 .97-.01*ii];
            end
            
            %%%%%%%%%%%%%%  LATCH TABGROUP INITIALIZATION  %%%%%%%%%%%%%%%%
            
            latchFiles = dir('latches/Subclasses/*.m');
            delete(get(app.LatchTabGroup, 'Children'));

            for file = latchFiles'
                [~, name, ~] = fileparts(file.name);
                tab = uitab(app.LatchTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.LatchParamIndices(name) = [ind, ind + length(params(1,:)) - 1];
                app.LatchParamMap(name) = params;
                for ii = 1:length(params(1,:))
                    paramName = params(1,ii);
                    defaultVal = str2double(params(2,ii));
                    limits = [str2double(params(3,ii)) str2double(params(4,ii))];
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.LatchEditFields(ind) = uieditfield(tab, 'numeric');
                    app.LatchEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.LatchEditFields(ind).Value = defaultVal;
                    app.LatchEditFields(ind).Limits = limits;
                    app.LatchLabels(ind) = uilabel(tab);
                    app.LatchLabels(ind).Text = paramName;
                    app.LatchLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                if isempty(app.latch_vars)
                    app.latch_vars = "latch " + params(1,:);
                end
                tab.BackgroundColor = [0.7137 0.9804 0.72-.01*ii];
            end
            
            %%%%%%%%%%%%%%  SPRING TABGROUP INITIALIZATION  %%%%%%%%%%%%%
            
            springFiles = dir('springs/Subclasses/*.m');
            delete(get(app.SpringTabGroup, 'Children'));

            for file = springFiles'
                [~, name, ~] = fileparts(file.name);
                tab = uitab(app.SpringTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.SpringParamIndices(name) = [ind, ind + length(params(1,:)) - 1];
                app.SpringParamMap(name) = params;
                for ii = 1:length(params(1,:))
                    paramName = params(1,ii);
                    defaultVal = str2double(params(2,ii));
                    limits = [str2double(params(3,ii)) str2double(params(4,ii))];
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.SpringEditFields(ind) = uieditfield(tab, 'numeric');
                    app.SpringEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.SpringEditFields(ind).Value = defaultVal;
                    app.SpringEditFields(ind).Limits = limits;
                    app.SpringLabels(ind) = uilabel(tab);
                    app.SpringLabels(ind).Text = paramName;
                    app.SpringLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                if isempty(app.spring_vars)
                    app.spring_vars = "spring " + params(1,:);
                end
                tab.BackgroundColor = [0.77 1-.01*length(name) 1.1-.01*length(name)];
            end

            %%%%%%%%%%%%%%%%%%%%%%%  LOADING MOTOR TABGROUP INITIALIZATION  %%%%%%%%%%%%%%%%
            
            motorFiles = dir('motors/Subclasses/*.m');
            delete(get(app.LoadingMotorTabGroup, 'Children'));

            for file = motorFiles'
                [~, name, ~] = fileparts(file.name);
                if strcmp(name, 'DeactivatingMotor')
                    continue;
                end
                loadTab = uitab(app.LoadingMotorTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.LoadingMotorParamIndices(name) = [ind, ind + length(params(1,:)) - 1];
                app.LoadingMotorParamMap(name) = params(1:2,:);
                for ii = 1:length(params(1,:))
                    paramName = params(1,ii);
                    loadDefaultVal = str2double(params(2,ii));
                    limits = [str2double(params(4,ii)) str2double(params(5,ii))];
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.LoadingMotorEditFields(ind) = uieditfield(loadTab, 'numeric');
                    app.LoadingMotorEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.LoadingMotorEditFields(ind).Value = loadDefaultVal;
                    app.LoadingMotorEditFields(ind).Limits = limits;
                    app.LoadingMotorLabels(ind) = uilabel(loadTab);
                    app.LoadingMotorLabels(ind).Text = paramName;
                    app.LoadingMotorLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                if isempty(app.um_vars)
                    app.um_vars = "unlatching motor " + params(1,:);
                    params = app.LoadingMotorParamMap(name);
                    app.lm_vars = "loading motor " + params(1,:);
                end
                loadTab.BackgroundColor = [1 1.3-.03*length(name) 1.3-.03*length(name)];
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%% UNLATCHING MOTOR INITIALIZATION %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            motorFiles = dir('motors/Subclasses/*.m');
            delete(get(app.UnlatchingMotorTabGroup, 'Children'));

            for file = motorFiles'
                [~, name, ~] = fileparts(file.name);
                tab = uitab(app.UnlatchingMotorTabGroup, 'Title', name);
                params = feval([name '.parameters']);
                app.UnlatchingMotorParamIndices(name) = [ind, ind + length(params(1,:)) - 1];
                app.UnlatchingMotorParamMap(name) = [params(1,:);params(3,:)];
                for ii = 1:length(params(1,:))
                    paramName = params(1,ii);
                    defaultVal = str2double(params(3,ii));
                    limits = [str2double(params(4,ii)) str2double(params(5,ii))];
                    col = mod(ii-1, 3);
                    row = floor((ii-1)/3);
                    app.UnlatchingMotorEditFields(ind) = uieditfield(tab, 'numeric');
                    app.UnlatchingMotorEditFields(ind).Position = [xPos+colSpacing*col yPos+row*25 width height];
                    app.UnlatchingMotorEditFields(ind).Value = defaultVal;
                    app.UnlatchingMotorEditFields(ind).Limits = limits;
                    app.UnlatchingMotorLabels(ind) = uilabel(tab);
                    app.UnlatchingMotorLabels(ind).Text = paramName;
                    app.UnlatchingMotorLabels(ind).Position = [xPos+colSpacing*col-labelSpacing yPos+row*25 150 height];
                    ind = ind + 1;
                end
                app.um_vars = "unlatching motor " + params(1,:);
                tab.BackgroundColor = [1 1.3-.03*length(name) 1.3-.03*length(name)];
            end
            
            % Updates the parameters dropdown menu
            cd ../;
            paramFiles = dir('parameters-library/*.txt');
            app.param_templates = {};
            for file = paramFiles'
                [~, name, ~] = fileparts(file.name);
                app.param_templates{end+1} = name;
            end
         
            update_templates(app);
            update_dd_vars(app);
            
            app.ParametersTabGroups = [app.MassTabGroup app.LatchTabGroup app.SpringTabGroup app.LoadingMotorTabGroup app.UnlatchingMotorTabGroup];
            app.CouplingNumberGroups = [app.ODCP_num app.HMCPX_num app.HMCPY_num];
            app.DropDownSelectionGroups = [app.OD_IV1DropDown app.IV1DropDown app.IV2DropDown];
            
            preferredumTab = app.UnlatchingMotorTabGroup.Children(2);
            app.UnlatchingMotorTabGroup.SelectedTab = preferredumTab;
        end

        % Selection change function: SpringTabGroup
        function SpringTabGroupSelectionChanged(app, event)
            selectedTab = app.SpringTabGroup.SelectedTab;
            params = app.SpringParamMap(selectedTab.Title);
            app.spring_vars = "spring " + params(1,:);
            update_dd_vars(app);
        end

        % Selection change function: LoadingMotorTabGroup
        function LoadingMotorTabGroupSelectionChanged(app, event)
            selectedTab = app.LoadingMotorTabGroup.SelectedTab;
            params = app.LoadingMotorParamMap(selectedTab.Title);
            app.lm_vars = "loading motor " + params(1,:);
            update_dd_vars(app);
        end

        % Selection change function: UnlatchingMotorTabGroup
        function UnlatchingMotorTabGroupSelectionChanged(app, event)
            selectedTab = app.UnlatchingMotorTabGroup.SelectedTab;
            params = app.UnlatchingMotorParamMap(selectedTab.Title);
            app.um_vars = "unlatching motor " + params(1,:);
            update_dd_vars(app);
        end

        % Selection change function: LatchTabGroup
        function LatchTabGroupSelectionChanged(app, event)
            selectedTab = app.LatchTabGroup.SelectedTab;
            params = app.LatchParamMap(selectedTab.Title);
            app.latch_vars = "latch " + params(1,:);
            update_dd_vars(app);
        end

        % Selection change function: MassTabGroup
        function MassTabGroupSelectionChanged(app, event)
            selectedTab = app.MassTabGroup.SelectedTab;
            params = app.MassParamMap(selectedTab.Title);
            app.load_vars = "load " + params(1,:);
            update_dd_vars(app);
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            lastwarn('');
            
            if (app.GraphingCorner.SelectedTab == app.GraphingCorner_heatmap)
                heatmap(app);
            elseif (app.GraphingCorner.SelectedTab == app.one_D)
                one_D_plot(app);
            elseif (app.GraphingCorner.SelectedTab == app.GraphingCorner_kinematics)
                kinematics(app);
            elseif (app.GraphingCorner.SelectedTab == app.GraphingCorner_sensitivity)
                sensitivity(app);
            end
            
        end

        % Value changed function: ODCP_num
        function ODCP_numValueChanged(app, event)
            num = app.ODCP_num.Value;
            if num == 0
                changeCPBackToZero(app, 'ODCP');
            else
                initiateCPTabs(app, num, 'ODCP', 17, 'x');
            end
        end

        % Value changed function: HMCPX_num
        function HMCPX_numValueChanged(app, event)
            num = app.HMCPX_num.Value;
            if num == 0
                changeCPBackToZero(app, 'HMCPX');
            else
                initiateCPTabs(app, num, 'HMCPX', 17, 'x');
            end
        end

        % Value changed function: HMCPY_num
        function HMCPY_numValueChanged(app, event)
            num = app.HMCPY_num.Value;
            if num == 0
                changeCPBackToZero(app, 'HMCPY');
            else
                initiateCPTabs(app, num, 'HMCPY', 17, 'y');
            end
        end

        % Button pushed function: saveParametersButton
        function saveParametersButtonPushed(app, event)
        % Change to the desired directory
        [filepath, ~, ~] = fileparts(mfilename('fullpath'));
        cd(filepath);
        cd ../;
        if ~exist("parameters-library", "dir")
            mkdir("parameters-library")
        end
        cd parameters-library;
        
        % Creates a directory with the user input name, overwriting if
        % it already exists
        fileName = [app.saveParametersEditField.Value '.xlsx'];    
        
        % Save the selected tabs index as the header of the file
        vnames = {'GroupIndex','SelectionIndex'};
        tabSelectionTable = array2table(nan(5,2), 'VariableNames', vnames);
        for tabGroupsIdx = 1:5
            [~, tabIdx] = ismember(app.ParametersTabGroups(tabGroupsIdx).SelectedTab, app.ParametersTabGroups(tabGroupsIdx).Children);
            tabSelectionTable.GroupIndex(tabGroupsIdx) = tabGroupsIdx;
            tabSelectionTable.SelectionIndex(tabGroupsIdx) = tabIdx;
        end
        
        try
            writetable(tabSelectionTable, fileName, 'Sheet', 1);
        catch
            warndlg("Could not save parameters because the template file is opened on your computer. Please close the template file " + ...
                "and try again.", "Error");
            return
        end
        
        % Saving current component parameter values
        numRow = length(app.dd_vars);
        vnames = {'Name', 'Index', 'Value'}; 
        names = cell(1, numRow);
        indices = zeros(1, numRow);
        values = zeros(1, numRow);
        for row = (1: numRow)
            % The variable name
            var = app.dd_vars(row); % The variable name
            [~, index] = ismember(var, app.dd_vars);
            efIndex = ddIndexToefIndex(app, index, var); % The edit-field index
            val = getEditFieldVal(app, var, efIndex); % The edit-field value
            names{row} = var;
            indices(row) = efIndex;
            values(row) = val;
        end
        parametersTable = table(app.dd_vars', indices', values', 'VariableNames', vnames);
        writetable(parametersTable, fileName, 'Sheet', 2);
        
        % Saving graphing corner dropdown selection
        vnames = {'Group', 'Index'};
        dropDownTable = array2table(nan(3,2), 'VariableNames', vnames);
        for idx = 1:3
            val = app.DropDownSelectionGroups(idx).Value;
            [~, index] = ismember(val, app.dd_vars);
            dropDownTable.Group(idx) = idx;
            dropDownTable.Index(idx) = index;
        end
        writetable(dropDownTable, fileName, 'Sheet', 3);
        
        % Saving three CP_num edit field values
        % vnames = {'Index', 'Value'};
        CPNumTable = array2table(nan(3,2), 'VariableNames', vnames);
        for idx = 1:3
            val = app.CouplingNumberGroups(idx).Value;
            CPNumTable.Index(idx) = idx;
            CPNumTable.Value(idx) = val;
        end
        writetable(CPNumTable, fileName, 'Sheet', 4);
        
        % Saving the relationship functions
        numRow = sum(CPNumTable.Value);
        vnames = {'Group', 'Index', 'Relationship'};
        groups = [repmat(1, CPNumTable.Value(1),1)' repmat(2, CPNumTable.Value(2),1)' repmat(3, CPNumTable.Value(3),1)'];
        indices = zeros(1, numRow);
        relationships = cell(1, numRow);
        for row = 1:numRow
            group = groups(row); % The tab group
            if group == 1
                relationships{row} = app.ODCPRelationships(row).Value;
                var = app.ODCPDropDowns(row).Value;                
            elseif group == 2
                relationships{row} = app.HMCPXRelationships(row - CPNumTable.Value(1)).Value;
                var = app.HMCPXDropDowns(row - CPNumTable.Value(1)).Value; 
            else
                relationships{row} = app.HMCPYRelationships(row - CPNumTable.Value(1) - CPNumTable.Value(2)).Value;
                var = app.HMCPYDropDowns(row - CPNumTable.Value(1) - CPNumTable.Value(2)).Value; 
            end
            [~, index] = ismember(var, app.dd_vars);
            indices(row) = index;
        end        
        relationshipTable = table(groups', indices', relationships', 'VariableNames', vnames)
        writetable(relationshipTable, fileName, 'Sheet', 5);
        
        update_templates(app);
        msgbox('Parameters saved successfully :)', 'Success')     
        end

        % Button pushed function: loadParametersButton
        function loadParametersButtonPushed(app, event)
            % Change into the desired directory
            [filepath, ~, ~] = fileparts(mfilename('fullpath'));
            cd(filepath);
            cd ../;
            if ~exist("parameters-library", "dir")
                mkdir("parameters-library")
            end
            cd parameters-library;
            
            % Find the desired text file name
            fileName = [app.loadParametersDropDown.Value '.xlsx'];
            
            % Change parameter tab group selection
            tabSelectionTable = readtable(fileName, 'Sheet', 1);
            for tabGroupIdx = 1:5
               tabIdx = tabSelectionTable.SelectionIndex(tabGroupIdx);
               app.ParametersTabGroups(tabGroupIdx).SelectedTab = app.ParametersTabGroups(tabGroupIdx).Children(tabIdx);
            end
            
            % Change parameter values
            parametersTable = readtable(fileName, 'Sheet', 2);
            [nrows, ~] = size(parametersTable);
            for row = 1:nrows
                changeEditField(app, parametersTable.Name(row), parametersTable.Index(row), parametersTable.Value(row));
            end
            
            % Loading graphing corner dropdown selection
            dropDownTable = readtable(fileName, 'Sheet', 3);
            for idx = 1:3
                app.DropDownSelectionGroups(idx).Value = app.dd_vars(dropDownTable.Index(idx));
            end
            
            % Change CP_num Edit Field Values
            CPNumTable = readtable(fileName, 'Sheet', 4);
            for idx = 1:3
                app.CouplingNumberGroups(idx).Value = CPNumTable.Value(idx);
                if idx == 1
                    num = app.ODCP_num.Value;
                    if num == 0
                        changeCPBackToZero(app, 'ODCP');
                    else
                        initiateCPTabs(app, num, 'ODCP', 17, 'x');
                    end
                elseif idx == 2
                    num = app.HMCPX_num.Value;
                    if num == 0
                        changeCPBackToZero(app, 'HMCPX');
                    else
                        initiateCPTabs(app, num, 'HMCPX', 17, 'x');
                    end
                else
                    num = app.HMCPY_num.Value;
                    if num == 0
                        changeCPBackToZero(app, 'HMCPY');
                    else
                        initiateCPTabs(app, num, 'HMCPY', 17, 'y');
                    end
                end
            end

            % Load the relationship functions
            relationshipTable = readtable(fileName, 'Sheet', 5);
            [nrows, ~] = size(relationshipTable);
            for row = 1:nrows
                % First change drop down selection, then the relationship
                % edit field
                group = relationshipTable.Group(row);
                if group == 1
                    index = relationshipTable.Index(row);
                    app.ODCPDropDowns(row).Value = app.dd_vars(index);
                    app.ODCPRelationships(row).Value = char(relationshipTable.Relationship(row));
                elseif group == 2
                    index = relationshipTable.Index(row);
                    app.HMCPXDropDowns(row - CPNumTable.Value(1)).Value = app.dd_vars(index);
                    app.HMCPXRelationships(row - CPNumTable.Value(1)).Value = char(relationshipTable.Relationship(row));
                else
                    index = relationshipTable.Index(row);
                    app.HMCPYDropDowns(row - CPNumTable.Value(1) - CPNumTable.Value(2)).Value = app.dd_vars(index);
                    app.HMCPYRelationships(row - CPNumTable.Value(1) - CPNumTable.Value(2)).Value = char(relationshipTable.Relationship(row));
                end
            end
            
            % Change CP Relationship Edit Field Values
            msgbox('Parameters loaded successfully :)', 'Success');         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpringTabGroup = uitabgroup(app.UIFigure);
            app.SpringTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SpringTabGroupSelectionChanged, true);
            app.Tab = uitab(app.SpringTabGroup);
            app.Tab.Title = 'Spring Tabs';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.Text = 'Go';
            app.LoadingMotorTabGroup = uitabgroup(app.UIFigure);
            app.LoadingMotorTabGroup.SelectionChangedFcn = createCallbackFcn(app, @LoadingMotorTabGroupSelectionChanged, true);
            app.Tab1 = uitab(app.LoadingMotorTabGroup);
            app.Tab1.Title = 'Loading Motor Tabs';
            app.UnlatchingMotorTabGroup = uitabgroup(app.UIFigure);
            app.UnlatchingMotorTabGroup.SelectionChangedFcn = createCallbackFcn(app, @UnlatchingMotorTabGroupSelectionChanged, true);
            app.Tab2 = uitab(app.UnlatchingMotorTabGroup);
            app.Tab2.Title = 'Unlatching Motor Tabs';
            app.LatchTabGroup = uitabgroup(app.UIFigure);
            app.LatchTabGroup.SelectionChangedFcn = createCallbackFcn(app, @LatchTabGroupSelectionChanged, true);
            app.Tab3 = uitab(app.LatchTabGroup);
            app.Tab3.Title = 'Latch Tabs';
            app.MassTabGroup = uitabgroup(app.UIFigure);
            app.MassTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MassTabGroupSelectionChanged, true);
            app.Tab4 = uitab(app.MassTabGroup);
            app.Tab4.Title = 'Load Mass Tabs';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.UnlatchingMotorLabel = uilabel(app.UIFigure);
            app.UnlatchingMotorLabel.Text = 'Unlatching Motor:';
            app.LoadingMotorLabel = uilabel(app.UIFigure);
            app.LoadingMotorLabel.Text = 'Loading Motor:';
            app.GraphingCorner = uitabgroup(app.UIFigure);
            app.GraphingCorner_kinematics = uitab(app.GraphingCorner);
            app.GraphingCorner_kinematics.Title = 'Kinematics';
            app.KinematicsOutputOptionsLabel = uilabel(app.GraphingCorner_kinematics);
            app.KinematicsOutputOptionsLabel.Text = 'Kinematics Output Options';
            app.forcedisp = uicheckbox(app.GraphingCorner_kinematics);
            app.forcedisp.Text = 'motor and spring force displacement curves';
            app.forcedisp.Value = true;
            app.latchkinematicsCheckBox = uicheckbox(app.GraphingCorner_kinematics);
            app.latchkinematicsCheckBox.Text = 'latch kinematics';
            app.latchkinematicsCheckBox.Value = true;
            app.loadkinematicsCheckBox = uicheckbox(app.GraphingCorner_kinematics);
            app.loadkinematicsCheckBox.Text = 'load kinematics';
            app.loadkinematicsCheckBox.Value = true;
            app.LoadingphaseLabel = uilabel(app.GraphingCorner_kinematics);
            app.LoadingphaseLabel.Text = 'Loading phase:';
            app.UnlatchingandlaunchingphaseLabel = uilabel(app.GraphingCorner_kinematics);
            app.UnlatchingandlaunchingphaseLabel.Text = 'Unlatching and launching phase:';
            app.one_D = uitab(app.GraphingCorner);
            app.one_D.Title = '1D Plot';
            app.OD_a_maxCheckBox = uicheckbox(app.one_D);
            app.OD_a_maxCheckBox.Text = 'a_max';
            app.xaxisLabel = uilabel(app.one_D);
            app.xaxisLabel.Text = 'x-axis';
            app.OD_y_maxCheckBox = uicheckbox(app.one_D);
            app.OD_y_maxCheckBox.Text = 'y_max';
            app.OD_y_unlatchCheckBox = uicheckbox(app.one_D);
            app.OD_y_unlatchCheckBox.Text = 'y_unlatch';
            app.OD_t_LCheckBox = uicheckbox(app.one_D);
            app.OD_t_LCheckBox.Text = 't_L';
            app.OD_v_toCheckBox = uicheckbox(app.one_D);
            app.OD_v_toCheckBox.Text = 'v_to';
            app.OD_P_maxCheckBox = uicheckbox(app.one_D);
            app.OD_P_maxCheckBox.Text = 'P_max';
            app.OD_P_maxCheckBox.Value = true;
            app.OD_t_toCheckBox = uicheckbox(app.one_D);
            app.OD_t_toCheckBox.Text = 't_to';
            app.OD_KE_maxCheckBox = uicheckbox(app.one_D);
            app.OD_KE_maxCheckBox.Text = 'KE_max';
            app.OD_IV1DropDown = uidropdown(app.one_D);
            app.OD_IV1DropDown.Items = {'--no selection--', 'Option 1', 'Option 2'};
            app.OD_IV1DropDown.Tooltip = {'Independent Variable 1'};
            app.yaxisOutputOptionsLabel = uilabel(app.one_D);
            app.yaxisOutputOptionsLabel.Text = 'y-axis Output Options';
            app.pixelsofresolutionLabel = uilabel(app.one_D);
            app.pixelsofresolutionLabel.Text = 'pixels of resolution =';
            app.OD_n = uieditfield(app.one_D, 'numeric');
            app.OD_n.Limits = [1 1024];
            app.OD_n.RoundFractionalValues = 'on';
            app.OD_n.ValueDisplayFormat = '%.0f';
            app.OD_n.Value = 100;
            app.xmaxEditFieldLabel = uilabel(app.one_D);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.OD_x_log_space = uiswitch(app.one_D, 'slider');
            app.OD_x_log_space.Items = {'lin', 'log'};
            app.OD_x_log_space.Value = 'lin';
            app.ComparetoDirectActuationCheckBox = uicheckbox(app.one_D);
            app.ComparetoDirectActuationCheckBox.Text = 'Compare to Direct Actuation';
            app.ComparetoDirectActuationCheckBox.Value = true;
            app.xminEditFieldLabel = uilabel(app.one_D);
            app.xminEditFieldLabel.Text = 'x min';
            app.OD_xmin = uieditfield(app.one_D, 'numeric');
            app.OD_xmin.Value = 0.01;
            app.OD_xmax = uieditfield(app.one_D, 'numeric');
            app.OD_xmax.Value = 12;
            app.ODCP_TabGroup = uitabgroup(app.one_D);
            app.CouplingParameterTabs = uitab(app.ODCP_TabGroup);
            app.CouplingParameterTabs.Title = 'Coupling Parameter Tabs';
            app.NumberofCouplingParametersLabel = uilabel(app.one_D);
            app.NumberofCouplingParametersLabel.Text = {'Number of Coupling'; 'Parameters'};
            app.ODCP_num = uieditfield(app.one_D, 'numeric');
            app.ODCP_num.ValueChangedFcn = createCallbackFcn(app, @ODCP_numValueChanged, true);
            app.GraphingCorner_heatmap = uitab(app.GraphingCorner);
            app.GraphingCorner_heatmap.Title = '2D Plot';
            app.a_maxCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.a_maxCheckBox.Text = 'a_max';
            app.a_maxCheckBox.Value = true;
            app.xaxisLabel_2 = uilabel(app.GraphingCorner_heatmap);
            app.xaxisLabel_2.Text = 'x-axis';
            app.yaxisLabel = uilabel(app.GraphingCorner_heatmap);
            app.yaxisLabel.Text = 'y-axis';
            app.y_maxCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.y_maxCheckBox.Text = 'y_max';
            app.y_unlatchCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.y_unlatchCheckBox.Text = 'y_unlatch';
            app.t_LCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.t_LCheckBox.Text = 't_L';
            app.v_toCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.v_toCheckBox.Text = 'v_to';
            app.v_toCheckBox.Value = true;
            app.P_maxCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.P_maxCheckBox.Text = 'P_max';
            app.P_maxCheckBox.Value = true;
            app.t_toCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.t_toCheckBox.Text = 't_to';
            app.t_toCheckBox.Value = true;
            app.KE_maxCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.KE_maxCheckBox.Text = 'KE_max';
            app.IV1DropDown = uidropdown(app.GraphingCorner_heatmap);
            app.IV1DropDown.Items = {'--no selection--'};
            app.IV1DropDown.Tooltip = {'Independent Variable 1'};
            app.IV1DropDown.Value = '--no selection--';
            app.xminEditFieldLabel_2 = uilabel(app.GraphingCorner_heatmap);
            app.xminEditFieldLabel_2.Text = 'x min';
            app.xmin = uieditfield(app.GraphingCorner_heatmap, 'numeric');
            app.xmin.Value = 100;
            app.xmaxEditFieldLabel_2 = uilabel(app.GraphingCorner_heatmap);
            app.xmaxEditFieldLabel_2.Text = 'x max';
            app.xmax = uieditfield(app.GraphingCorner_heatmap, 'numeric');
            app.xmax.Value = 300000;
            app.yminLabel = uilabel(app.GraphingCorner_heatmap);
            app.yminLabel.Text = 'y min';
            app.ymin = uieditfield(app.GraphingCorner_heatmap, 'numeric');
            app.ymin.Value = 1;
            app.ymaxLabel = uilabel(app.GraphingCorner_heatmap);
            app.ymaxLabel.Text = 'y max';
            app.ymax = uieditfield(app.GraphingCorner_heatmap, 'numeric');
            app.ymax.Value = 1000;
            app.HeatmapOutputOptionsLabel = uilabel(app.GraphingCorner_heatmap);
            app.HeatmapOutputOptionsLabel.Text = 'Heatmap Output Options';
            app.pixelsofresolutionLabel_2 = uilabel(app.GraphingCorner_heatmap);
            app.pixelsofresolutionLabel_2.Text = 'pixels of resolution =';
            app.n = uieditfield(app.GraphingCorner_heatmap, 'numeric');
            app.n.Limits = [1 1024];
            app.n.RoundFractionalValues = 'on';
            app.n.ValueDisplayFormat = '%.0f';
            app.n.Value = 20;
            app.x_log_space = uiswitch(app.GraphingCorner_heatmap, 'slider');
            app.x_log_space.Items = {'lin', 'log'};
            app.x_log_space.Value = 'log';
            app.y_log_space = uiswitch(app.GraphingCorner_heatmap, 'slider');
            app.y_log_space.Items = {'lin', 'log'};
            app.y_log_space.Value = 'log';
            app.MakeLaMSAZonePlotCheckBox = uicheckbox(app.GraphingCorner_heatmap);
            app.MakeLaMSAZonePlotCheckBox.Text = 'Make LaMSA Zone Plot';
            app.MakeLaMSAZonePlotCheckBox.Value = true;
            app.CP_DropDown_Label_3 = uilabel(app.GraphingCorner_heatmap);
            app.CP_DropDown_Label_3.Text = 'Coupling Parameter';
            app.CP_DropDown_3 = uidropdown(app.GraphingCorner_heatmap);
            app.CP_Relationship_Label_3 = uilabel(app.GraphingCorner_heatmap);
            app.CP_Relationship_Label_3.Text = {'Coupling Relationship'; ''};
            app.zEditFieldLabel_3 = uilabel(app.GraphingCorner_heatmap);
            app.zEditFieldLabel_3.Text = 'z  =';
            app.CP_Relationship_3 = uieditfield(app.GraphingCorner_heatmap, 'text');
            app.CP_Relationship_3.Value = 'y';
            app.HMCPX_TabGroup = uitabgroup(app.GraphingCorner_heatmap);
            app.CouplingParameterTabs_2 = uitab(app.HMCPX_TabGroup);
            app.CouplingParameterTabs_2.Title = 'Coupling Parameter Tabs';
            app.HMCPY_TabGroup = uitabgroup(app.GraphingCorner_heatmap);
            app.CouplingParameterTabs_3 = uitab(app.HMCPY_TabGroup);
            app.CouplingParameterTabs_3.Title = 'Coupling Parameter Tabs';
            app.NumberofCouplingParametersLabel_2 = uilabel(app.GraphingCorner_heatmap);
            app.NumberofCouplingParametersLabel_2.Text = 'Number of Coupling Parameters';
            app.HMCPX_num = uieditfield(app.GraphingCorner_heatmap, 'numeric');
            app.HMCPX_num.ValueChangedFcn = createCallbackFcn(app, @HMCPX_numValueChanged, true);
            app.NumberofCouplingParametersLabel_3 = uilabel(app.GraphingCorner_heatmap);
            app.NumberofCouplingParametersLabel_3.Text = 'Number of Coupling Parameters';
            app.HMCPY_num = uieditfield(app.GraphingCorner_heatmap, 'numeric');
            app.HMCPY_num.ValueChangedFcn = createCallbackFcn(app, @HMCPY_numValueChanged, true);
            app.IV2DropDown = uidropdown(app.GraphingCorner_heatmap);
            app.IV2DropDown.Items = {'--no selection--'};
            app.IV2DropDown.Tooltip = {'Independent Variable 1'};
            app.IV2DropDown.Value = '--no selection--';
            app.GraphingCorner_sensitivity = uitab(app.GraphingCorner);
            app.GraphingCorner_sensitivity.Title = 'Sensitivity';
            app.sensitivity_vars = uilistbox(app.GraphingCorner_sensitivity);
            app.sensitivity_vars.Multiselect = 'on';
            app.sensitivity_vars.Value = {'Item 1'};
            app.MetricButtonGroup = uibuttongroup(app.GraphingCorner_sensitivity);
            app.MetricButtonGroup.Title = 'Metric';
            app.ymaxButton = uiradiobutton(app.MetricButtonGroup);
            app.ymaxButton.Text = 'ymax';
            app.yunlatchButton = uiradiobutton(app.MetricButtonGroup);
            app.yunlatchButton.Text = 'yunlatch';
            app.tLButton = uiradiobutton(app.MetricButtonGroup);
            app.tLButton.Text = 'tL';
            app.vtoButton = uiradiobutton(app.MetricButtonGroup);
            app.vtoButton.Text = 'vto';
            app.PmaxButton = uiradiobutton(app.MetricButtonGroup);
            app.PmaxButton.Text = 'Pmax';
            app.PmaxButton.Value = true;
            app.ttoButton = uiradiobutton(app.MetricButtonGroup);
            app.ttoButton.Text = 'tto';
            app.KEmaxButton = uiradiobutton(app.MetricButtonGroup);
            app.KEmaxButton.Text = 'KEmax';
            app.SensitivityLabel = uilabel(app.GraphingCorner_sensitivity);
            app.SensitivityLabel.Text = {'Variables'; '(press CTRL to select multiple)'};
            app.GraphingCornerLabel = uilabel(app.UIFigure);
            app.GraphingCornerLabel.Text = 'Graphing Corner';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph!';
            app.savesolutionCheckBox = uicheckbox(app.UIFigure);
            app.savesolutionCheckBox.Text = 'save solution?';
            app.saveParametersButton = uibutton(app.UIFigure, 'push');
            app.saveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @saveParametersButtonPushed, true);
            app.saveParametersButton.Text = 'Save Workspace As';
            app.saveParametersEditField = uieditfield(app.UIFigure, 'text');
            app.saveParametersEditField.Value = '(Enter Name Here)';
            app.loadParametersButton = uibutton(app.UIFigure, 'push');
            app.loadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @loadParametersButtonPushed, true);
            app.loadParametersButton.Text = 'Load Workspace';
            app.loadParametersDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end