classdef apptrial1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        go                            matlab.ui.control.Button
        LaMSAComponentLabel           matlab.ui.control.Label
        LoadMassLabel                 matlab.ui.control.Label
        LoadingMotorDropDownLabel     matlab.ui.control.Label
        LoadingMotorDropDown          matlab.ui.control.DropDown
        UnlatchingMotorDropDownLabel  matlab.ui.control.Label
        UnlatchingMotorDropDown       matlab.ui.control.DropDown
        LatchLabel                    matlab.ui.control.Label
        ComponentAttributesLabel      matlab.ui.control.Label
        massEditFieldLabel            matlab.ui.control.Label
        load_mass_mass                matlab.ui.control.NumericEditField
        massEditFieldLabel_2          matlab.ui.control.Label
        latch_mass                    matlab.ui.control.NumericEditField
        radiusLabel                   matlab.ui.control.Label
        latch_radius                  matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        latch_coeff_fric              matlab.ui.control.NumericEditField
        v_0Label                      matlab.ui.control.Label
        latch_v_0                     matlab.ui.control.NumericEditField
        spring                        matlab.ui.container.TabGroup
        linear_spring                 matlab.ui.container.Tab
        kLabel                        matlab.ui.control.Label
        linear_spring_k               matlab.ui.control.NumericEditField
        massEditFieldLabel_4          matlab.ui.control.Label
        linear_spring_mass            matlab.ui.control.NumericEditField
        maxforceLabel                 matlab.ui.control.Label
        linear_spring_Fmax            matlab.ui.control.NumericEditField
        exponential_spring            matlab.ui.container.Tab
        kLabel_2                      matlab.ui.control.Label
        exp_spring_k                  matlab.ui.control.NumericEditField
        massEditFieldLabel_5          matlab.ui.control.Label
        exp_spring_mass               matlab.ui.control.NumericEditField
        characteristiclengthLabel     matlab.ui.control.Label
        exp_spring_char_len           matlab.ui.control.NumericEditField
        maxforceLabel_2               matlab.ui.control.Label
        exp_spring_Fmax               matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: go
        function goButtonPushed(app, event)
            addpath(genpath(pwd));
            
            load = load_mass(app.load_mass_mass.Value);
            
            latch = rounded_latch(app.latch_radius.Value,app.latch_mass.Value,app.latch_coeff_fric.Value, app.latch_v_0.Value);
            disp(app.latch_coeff_fric.Value)
            
            if (app.spring.SelectedTab == app.linear_spring)
                spring = linear_spring(app.linear_spring_k.Value,app.linear_spring_mass.Value,app.linear_spring_Fmax.Value);
                disp(app.linear_spring_Fmax.Value)
            elseif (app.spring.SelectedTab == app.exponential_spring)
                spring = exponential_spring(app.linear_spring_k.Value,10);
            end
            
            loading_motor = linear_motor(100,100,10);
            
            % unlatching_motor = linear_motor(100,100,10);
            
            dateString = string(datetime);
            cleanDateString = regexprep(dateString, " ", "_");
            cleanDateString = regexprep(cleanDateString, ":", "_");
            
            
            %% Plot Stuff
            
            N=1; % determines resolution of heatplots

            % setting x axis on the plot (Fmax of latch)
            xname = 'Fmax';
            xrange = [-1 3];
            Fmaxs = logspace(xrange(1),xrange(2),N);
            
            %setting y axis value on plot (Vmax of latch)
            yname = 'vmax';
            yrange = [-2 2];
            v_maxs = logspace(yrange(1),yrange(2),N);
            
            metrics = {'tto','vto','Pmax','ymax','tL','KEmax','yunlatch'};
            load_time_constraint = Inf;
            
            for ii=1:length(metrics)
                outval{ii}=zeros(N);
            end
            for i=1:N %iterate over y-axis-variable of plot
                for j=1:N %iterate over x-axis-variable of plot
                    unlatching_motor = hill_muscle_motor(5, Fmaxs(j), v_maxs(i),3);
                    [sol,transition_times]=solve_model(loading_motor,unlatching_motor,load,latch,spring, cleanDateString);
                    met_dict=get_metrics(sol,transition_times,load ,metrics);
                    for ii=1:length(metrics)
                        outval{ii}(i,j)=met_dict(metrics{ii});
                    end
                end
                disp(['row ' num2str(i) ' of ' num2str(N)]);
            end
               
            % plot output
            figure
            n=1;
            for ii=1:length(metrics)
                subplot(2,4,n);
                imagesc(xrange,yrange,outval{ii});
                set(gca,'YDir','normal');
                xlabel(xname,'Interpreter', 'Latex');
                ylabel(yname, 'Interpreter', 'Latex');
                c = colorbar;
                c.Label.String = metrics{ii};
                n=n+1;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.go = uibutton(app.UIFigure, 'push');
            app.go.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.LaMSAComponentLabel = uilabel(app.UIFigure);
            app.LaMSAComponentLabel.Text = 'LaMSA Component';
            app.LoadMassLabel = uilabel(app.UIFigure);
            app.LoadMassLabel.Text = 'Load Mass';
            app.LoadingMotorDropDownLabel = uilabel(app.UIFigure);
            app.LoadingMotorDropDownLabel.Text = 'Loading Motor';
            app.LoadingMotorDropDown = uidropdown(app.UIFigure);
            app.LoadingMotorDropDown.Items = {'Linear Motor', 'Hill Muscle Motor'};
            app.LoadingMotorDropDown.Value = 'Linear Motor';
            app.UnlatchingMotorDropDownLabel = uilabel(app.UIFigure);
            app.UnlatchingMotorDropDownLabel.Text = 'Unlatching Motor';
            app.UnlatchingMotorDropDown = uidropdown(app.UIFigure);
            app.UnlatchingMotorDropDown.Items = {'Linear Motor', 'Hill Muscle Motor'};
            app.UnlatchingMotorDropDown.Value = 'Linear Motor';
            app.LatchLabel = uilabel(app.UIFigure);
            app.LatchLabel.Text = 'Latch';
            app.ComponentAttributesLabel = uilabel(app.UIFigure);
            app.ComponentAttributesLabel.Text = 'Component Attributes';
            app.massEditFieldLabel = uilabel(app.UIFigure);
            app.massEditFieldLabel.Text = 'mass =';
            app.load_mass_mass = uieditfield(app.UIFigure, 'numeric');
            app.load_mass_mass.Tooltip = {'required'};
            app.massEditFieldLabel_2 = uilabel(app.UIFigure);
            app.massEditFieldLabel_2.Text = 'mass =';
            app.latch_mass = uieditfield(app.UIFigure, 'numeric');
            app.latch_mass.Tooltip = {'required'};
            app.radiusLabel = uilabel(app.UIFigure);
            app.radiusLabel.Text = 'radius =';
            app.latch_radius = uieditfield(app.UIFigure, 'numeric');
            app.latch_radius.Tooltip = {'required'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'μ =';
            app.latch_coeff_fric = uieditfield(app.UIFigure, 'numeric');
            app.latch_coeff_fric.Limits = [0 Inf];
            app.latch_coeff_fric.Tooltip = {'optional'};
            app.v_0Label = uilabel(app.UIFigure);
            app.v_0Label.Text = 'v_0 =';
            app.latch_v_0 = uieditfield(app.UIFigure, 'numeric');
            app.latch_v_0.Tooltip = {'optional'};
            app.spring = uitabgroup(app.UIFigure);
            app.linear_spring = uitab(app.spring);
            app.linear_spring.Title = 'Linear Spring';
            app.kLabel = uilabel(app.linear_spring);
            app.kLabel.Text = 'k =';
            app.linear_spring_k = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_k.Tooltip = {'required'};
            app.massEditFieldLabel_4 = uilabel(app.linear_spring);
            app.massEditFieldLabel_4.Text = 'mass =';
            app.linear_spring_mass = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_mass.Tooltip = {'required'};
            app.maxforceLabel = uilabel(app.linear_spring);
            app.maxforceLabel.Text = 'max force =';
            app.linear_spring_Fmax = uieditfield(app.linear_spring, 'numeric');
            app.linear_spring_Fmax.Tooltip = {'required'};
            app.linear_spring_Fmax.Value = Inf;
            app.exponential_spring = uitab(app.spring);
            app.exponential_spring.Title = 'Exponential Spring';
            app.kLabel_2 = uilabel(app.exponential_spring);
            app.kLabel_2.Text = 'k =';
            app.exp_spring_k = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_k.Tooltip = {'required'};
            app.massEditFieldLabel_5 = uilabel(app.exponential_spring);
            app.massEditFieldLabel_5.Text = 'mass =';
            app.exp_spring_mass = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_mass.Tooltip = {'required'};
            app.characteristiclengthLabel = uilabel(app.exponential_spring);
            app.characteristiclengthLabel.Text = 'characteristic length =';
            app.exp_spring_char_len = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_char_len.Tooltip = {'required'};
            app.maxforceLabel_2 = uilabel(app.exponential_spring);
            app.maxforceLabel_2.Text = 'max force =';
            app.exp_spring_Fmax = uieditfield(app.exponential_spring, 'numeric');
            app.exp_spring_Fmax.Tooltip = {'required'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptrial1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end