classdef practiceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GoButton            matlab.ui.control.Button
        xaxisDropDown       matlab.ui.control.DropDown
        xaxisDropDownLabel  matlab.ui.control.Label
        SpringTabGroup      matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
    end

    
    properties (Access = public)
        SpringTabs        = matlab.ui.container.Tab %list of tabs representing types of springs
        SpringEditFields  = matlab.ui.control.EditField %list of handles to dynamicaly generated edit fields
        SpringLabels      = matlab.ui.control.Label %list of handles to dynamicaly generated labels
    end
    
    properties (Access = private)
        SpringParamMap = containers.Map;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            files = dir(fullfile('Subclasses', '*.m'));
            delete(get(app.SpringTabGroup, 'Children'));
            
            yPos = 150;
            ind = 1;
            
            for file = files'
                [~, name, ~] = fileparts(file.name); % get subclass name
                
                tab = uitab(app.SpringTabGroup, 'Title', name); % make a tab for it
                
                params = feval([name '.parameters']); % string array of parameters
                app.SpringParamMap(name) = feval([name '.parameters']);
                for ii = 1:length(params)
                    param = params(ii);
                    app.SpringEditFields(ind) = uieditfield(tab);
                    app.SpringEditFields(ind).Position = [150 yPos-ii*25 148 22];
                    
                    app.SpringLabels(ind) = uilabel(tab);
                    app.SpringLabels(ind).Text = param;
                    app.SpringLabels(ind).Position = [10 yPos-ii*25 150 22];
                    
                    ind = ind + 1;
                end
                
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            tab = app.SpringTabGroup.SelectedTab
        end

        % Selection change function: SpringTabGroup
        function SpringTabGroupSelectionChanged(app, event)
            selectedTab = app.SpringTabGroup.SelectedTab;
            app.xaxisDropDown.Items = app.SpringParamMap(selectedTab.Title);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpringTabGroup = uitabgroup(app.UIFigure);
            app.SpringTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SpringTabGroupSelectionChanged, true);
            app.Tab = uitab(app.SpringTabGroup);
            app.Tab.Title = 'Tab2';
            app.xaxisDropDownLabel = uilabel(app.UIFigure);
            app.xaxisDropDownLabel.Text = 'x axis';
            app.xaxisDropDown = uidropdown(app.UIFigure);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practiceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end